classdef DialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ChooseROIColorsListBox       matlab.ui.control.ListBox
        ChooseROIColorsListBoxLabel  matlab.ui.control.Label
        AddROIButton                 matlab.ui.control.Button
        HighlightPixelsButton        matlab.ui.control.Button
        AxesScatterPlot              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   
        
        RoiCounter = 0
        RoiVertices = {};
        Data
        MyColorMap = [1 0 0; 0 1 0; 0 0 1; 1 1 0; 1 0 1; 0 1 1] % RGB Yellow Magenta Cyan
        Intensity;
        Rows;
        Columns;
        PCX
        PCY
        CustomColorMap = []; 

        Datacube
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, intensity, data, rows, columns, PCX, PCY)
            % we set the CallingApp property
            app.CallingApp = caller;

            app.Intensity = intensity;

            % we draw the scatterplot
            scatter(app.AxesScatterPlot, data(:,PCX), data(:,PCY), '.', 'MarkerEdgeColor',[0 0 0]);
            grid(app.AxesScatterPlot, 'on')
            axis(app.AxesScatterPlot, 'padded')
            
            
            % we save the Data as a 2 column vector % WE PROBABLY DON'T
            % NEED TO CONCATENATE THE DATA IN 2 COLUMNS IF WE ACCESS WITH
            % PCX AND PCY
            %app.Data = cat(2,data(:,PCX),data(:,PCY));
            app.Data = data;
            
            % we save the # of cols and rows
            app.Rows = rows;
            app.Columns = columns;

            % we save the PCX and PCY
            app.PCX = PCX;
            app.PCY = PCY;


        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
        % We want to add a ROI at each button press        

        % After pressing the button we disable the button until the ROI is drawn
        app.AddROIButton.Enable = "off";                       
        roi = drawpolygon(app.AxesScatterPlot);
        
        % after completing a polygon we increase the ROI counter and we label the ROI
        app.RoiCounter = app.RoiCounter +1;
        % roi.Label =strcat('ROI', num2str(app.RoiCounter));
        % roi.LabelVisible = 'off';

        % then we add a new item to the list box
        newItemName = strcat('ROI', num2str(app.RoiCounter));
        app.ChooseROIColorsListBox.Items(end+1) = {newItemName};
        % n = num2cell(app.RoiCounter);
        app.ChooseROIColorsListBox.ItemsData(end+1) = app.RoiCounter;
        % we set up a parameter i that goes from 1 to the number of colors
        % in MyColorMap, which is 6
        
        % we only save the vertices coordinates of the completed
        % polygons
        app.RoiVertices{app.RoiCounter} = roi.Position;

        % we create the associated color to it
        i = rem(app.RoiCounter, size(app.MyColorMap,1));
        if i == 0
            i = size(app.MyColorMap,1);
        end
        app.CustomColorMap(app.RoiCounter,:) = app.MyColorMap(i,:);
        
        % we re-enable the button
        app.AddROIButton.Enable = "on";
    

        end

        % Value changed function: ChooseROIColorsListBox
        function ChooseROIColorsListBoxValueChanged(app, event)
            value = app.ChooseROIColorsListBox.Value;            
            userDefinedColor = uisetcolor;
            if length(userDefinedColor) == 3
                % insert: if no output is given, then do nothing!
                style = uistyle(BackgroundColor = userDefinedColor);
                addStyle(app.ChooseROIColorsListBox, style, "item", value);            
                % we assign the new color to the custom Color Map
                app.CustomColorMap(value, :) = userDefinedColor;
                % we redraw the same ROI with the new color
                drawpolygon(app.AxesScatterPlot, 'Position', ...
                app.RoiVertices{value}, 'Color', app.CustomColorMap(value,:));
            end
            % re-focus on the figure
            figure(app.UIFigure)
            set(app.ChooseROIColorsListBox, 'value', {});
        end

        % Button pushed function: HighlightPixelsButton
        function HighlightPixelsButtonPushed(app, event)
            dataMatrix = reshape(app.CallingApp.Datacube, app.Rows*app.Columns, size(app.CallingApp.Datacube,3));
            
            % we allocate memory for the 2D masks/maps
            inROIMap = cell(1, app.RoiCounter);
            avgSpectrum = cell(1, app.RoiCounter);
            stdDeviation = cell(1, app.RoiCounter);

            % for starters we take as grey image the PC1 scores
            % PC1scores = app.Data(:,1);
            % reshapedData = reshape(PC1scores, app.Rows, app.Columns);
            % grayImage = mat2gray(reshapedData);
            grayImage = mat2gray(app.Intensity);
            grayImageR = grayImage;
            grayImageG = grayImage;
            grayImageB = grayImage;


            % for every polygon drawn, create a mask that identifies and colors the
            % pixels and recolors the ROIs on the scatterplot.
            for i = 1:app.RoiCounter

                % identify the pixels in the polygon
                inROI = inpolygon(app.Data(:,app.PCX), app.Data(:,app.PCY), app.RoiVertices{i}(:,1), ...
                    app.RoiVertices{i}(:,2));
                inROIMap{i} = reshape(inROI, app.Rows, app.Columns);        
           
                grayImageR(inROIMap{i}) = app.CustomColorMap(i, 1);
                grayImageG(inROIMap{i}) = app.CustomColorMap(i, 2);
                grayImageB(inROIMap{i}) = app.CustomColorMap(i, 3);

                avgSpectrum{i} = sum(dataMatrix(inROI,:))/sum(inROI);
                stdDeviation{i} = std(dataMatrix(inROI,:));
                
            end
            

            grayImageColoredPixels = cat(3, grayImageR,grayImageG,grayImageB);

            figure;
            subplot(1,2,1)
            imshow(grayImageColoredPixels)

            % create the average spectrum of the ROIs
            subplot(1,2,2)
            ax = gca;
            for i = 1:app.RoiCounter              
                xi = [1:size(app.CallingApp.Datacube,3), size(app.CallingApp.Datacube,3):-1:1];
                yi = [stdDeviation{i}+avgSpectrum{i}, flip(avgSpectrum{i}-stdDeviation{i})];                
                patch( ax, xi, yi, app.CustomColorMap(i,:), 'EdgeAlpha', 0 );
                hold on
                plot(1:size(app.CallingApp.Datacube,3), avgSpectrum{i}, 'linewidth', 2, 'color', app.CustomColorMap(i,:));                
            end
            alpha(ax, 0.1)
            hold off
            axis square

            % I color every ROI item in the listbox


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Options button in main app, if the app is
            % still open
            if isvalid(app.CallingApp)
                app.CallingApp.SelectPixelsButton.Enable = "on";
            end
            
            delete(app)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AxesScatterPlot = uiaxes(app.UIFigure);
            title(app.AxesScatterPlot, 'Title')
            xlabel(app.AxesScatterPlot, 'X')
            ylabel(app.AxesScatterPlot, 'Y')
            zlabel(app.AxesScatterPlot, 'Z')
            app.HighlightPixelsButton = uibutton(app.UIFigure, 'push');
            app.HighlightPixelsButton.ButtonPushedFcn = createCallbackFcn(app, @HighlightPixelsButtonPushed, true);
            app.HighlightPixelsButton.Text = 'Highlight Pixels';
            app.AddROIButton = uibutton(app.UIFigure, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.ChooseROIColorsListBoxLabel = uilabel(app.UIFigure);
            app.ChooseROIColorsListBoxLabel.Text = 'Choose ROI Colors';
            app.ChooseROIColorsListBox = uilistbox(app.UIFigure);
            app.ChooseROIColorsListBox.Items = {};
            app.ChooseROIColorsListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseROIColorsListBoxValueChanged, true);
            app.ChooseROIColorsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end