classdef PCA_imaging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        VisualizeButton           matlab.ui.control.Button
        ButtonGroup               matlab.ui.container.ButtonGroup
        PerformPCAButton          matlab.ui.control.Button
        SpectralButton            matlab.ui.control.RadioButton
        ClassicButton             matlab.ui.control.RadioButton
        SpatialAnalysisButton     matlab.ui.control.Button
        LoadCalibrationButton     matlab.ui.control.Button
        SelectPixelsButton        matlab.ui.control.Button
        IntensityThresholdSlider  matlab.ui.control.Slider
        ThresholdLabel            matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        PCYSpinner                matlab.ui.control.Spinner
        PCYSpinnerLabel           matlab.ui.control.Label
        PCXSpinner                matlab.ui.control.Spinner
        PCXSpinnerLabel           matlab.ui.control.Label
        AxesExplainedVariance     matlab.ui.control.UIAxes
        AxIntensityMap            matlab.ui.control.UIAxes
        AxPCScoresHist            matlab.ui.control.UIAxes
        AxLoadingFunY             matlab.ui.control.UIAxes
        AxPCYScoreMap             matlab.ui.control.UIAxes
        AxLoadingFunX             matlab.ui.control.UIAxes
        AxPCXScoreMap             matlab.ui.control.UIAxes
        AxThresholdMask           matlab.ui.control.UIAxes
        AxIntensityHistogram      matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Bins = 2^8;


        IntensityCounts =[];
        BinEdges =[];
        MaxIntensity;
        IntensityThreshold;
        % Mask = [];

        DataMatrix;
        V = [];


        % other apps
        DialogApp; % Description
        %SpatialAnalysis;

        cmap = jet % colormap of the figures


        Datacube = [];
        Mask = [];
        Intensity = [];
        Scores;
        Rows;
        Columns;
        Depth;
        CurrentThresholdLine %
        Explained
        Mu
        nonNanIndices
        Calibration = [];
        avgSpectrum % vector with average spectrum of non-Nan elements
        avgSpectrumPCscores % Vector with PC scores of the average spectrum
        Filename
        Filepath
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % we disable the buttons that cannot work without executing the
            % program once first
            app.SelectPixelsButton.Enable = "off";
            app.PerformPCAButton.Enable = "off";
            %app.SpatialAnalysisButton.Enable = "off";

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % For the LOAD button to work I expect the datacube to be
            % a variable called "data" in the .mat file and (optional) the
            % wavelengths to be called "WL"

            app.LoadButton.Enable = "off";

            [app.Filename, app.Filepath] = uigetfile('*.mat', 'Select a file', "C:\");
            % we re-focus on the main page
            figure(app.UIFigure)

            % we load the chosen .mat file in the workspace
            load(strcat(app.Filepath,app.Filename));


            % we save the datacube as a property
            if size(Hyperspectrum_cube,3)==2.*length(f) %if the spectral hypecube is complex the real and imag are stacked one over the other
                Hyperspectrum_cube_real=Hyperspectrum_cube(:,:,1:size(Hyperspectrum_cube,3)./2);
                Hyperspectrum_cube_imag=Hyperspectrum_cube(:,:,size(Hyperspectrum_cube,3)./2+1:end);
                clear Hyperspectrum_cube
                Hyperspectrum_cube=zeros(size(Hyperspectrum_cube_real));
                Hyperspectrum_cube_complex=Hyperspectrum_cube_real+1i.*Hyperspectrum_cube_imag;
                Hyperspectrum_cube=abs(Hyperspectrum_cube_complex); %I must work with the absolute values
                clear Hyperspectrum_cube_real Hyperspectrum_cube_imag
            end
            app.Datacube = abs(Hyperspectrum_cube); %absolute value also for complex dataset saved in double 


            % we save the dimensions of the datacube
            rows = size(app.Datacube,1);
            columns = size(app.Datacube,2);
            depth = size(app.Datacube,3);

            app.Rows = rows;
            app.Columns = columns;
            app.Depth = depth;

            % we create the calibration vector
            if exist('WL', 'var')
                app.Calibration = WL; % how to treat if not called WL?
                % Create an appropriate button
            else
                app.Calibration = 1:app.Depth;
            end
            clear data;


            % we plot the intensity as the sum of all counts in the top
            % axes
            intensity = sum(app.Datacube, 3);
            imagesc(app.AxIntensityMap, intensity);
            colormap(app.AxIntensityMap, "jet")
            pbaspect(app.AxIntensityMap, [app.Columns, app.Rows, 1]);
            app.AxIntensityMap.XLim = [0 app.Columns];
            app.AxIntensityMap.YLim = [0 app.Rows];

            % we save the intensity counts and bin edges for future use
            [intensityCounts,binEdges] = histcounts(intensity,app.Bins);
            app.IntensityCounts = intensityCounts;
            app.BinEdges = binEdges;

            % we plot the histogram in the ax Intensity histogram
            % we save the intensity matrix as a property
            app.Intensity = intensity;
            app.MaxIntensity = max(max(intensity));
            histogram(app.AxIntensityHistogram, intensity, app.Bins);
            app.AxIntensityHistogram.XLim = [0 app.MaxIntensity];

            % set the threshold as a mask with 1 everywhere
            app.Mask = ones(rows, columns);
            imagesc(app.AxThresholdMask, app.Mask, [0 1]);
            pbaspect(app.AxThresholdMask, [app.Columns app.Rows 1])
            app.AxThresholdMask.XLim = [0 app.Columns];
            app.AxThresholdMask.YLim = [0 app.Rows];

            %Enable the buttons
            app.PerformPCAButton.Enable = "on";
            app.LoadButton.Enable = "on";

        end

        % Button pushed function: LoadCalibrationButton
        function LoadCalibrationButtonPushed(app, event)
            %
            % [app.Filename, app.Filepath] = uigetfile('*.mat', 'Select a Calibration File', ...
            %     defname ="C:\Users\frcar\Desktop\UB TG HSI Datasets"  );
            % app.Calibration =
        end

        % Value changing function: IntensityThresholdSlider
        function IntensityThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            %histogram(app.AxIntensityHistogram, app.Intensity, app.Bins);
            hold(app.AxIntensityHistogram, 'on')

            if isgraphics(app.CurrentThresholdLine)
                delete(app.CurrentThresholdLine);
            end

            % we want the threshold to be quantized as the bins are
            app.CurrentThresholdLine = xline(app.AxIntensityHistogram,...
                ((changingValue*app.Bins)/100)*app.MaxIntensity/app.Bins );


            % we hide the legend while the value is changing
            legend(app.AxIntensityHistogram, 'hide')

            hold(app.AxIntensityHistogram, 'off')


        end

        % Value changed function: IntensityThresholdSlider
        function IntensityThresholdSliderValueChanged(app, event)
            value = app.IntensityThresholdSlider.Value;

            % when we change the threshold, we need to redo any PC
            % calculation that we may have done
            %app.AlreadyDonePCs = zeros(1, app.Depth);

            app.IntensityThreshold = round((value*app.Bins)/100)*app.MaxIntensity/app.Bins;

            % we write the threshold in a legend in a 2-decimal-digits
            % format
            formattedThreshold = sprintf('%0.2e', app.IntensityThreshold );
            legend(app.AxIntensityHistogram, formattedThreshold );

            % we create the mask
            app.Mask = NaN(size(app.Intensity));
            app.Mask(app.Intensity >= app.IntensityThreshold) = 1;

            % we draw the mask
            % imagesc(app.AxThresholdMask, app.Mask);
            blackNan(app.Mask, [], [], app.AxThresholdMask);
            app.AxThresholdMask.XLim = [0 app.Columns];
            app.AxThresholdMask.YLim = [0 app.Rows];

        end

        % Button pushed function: PerformPCAButton
        function PerformPCAButtonPushed(app, event)
            % to perform PCA we must transform the datacube into a matrix
            datamatrix = reshape(app.Datacube, app.Rows*app.Columns, app.Depth);

            % We apply PCA only on non Nan elements identified by the intensity
            % threshold mask.
            % indexMatrix is a matrix that contains the indices of all elements,
            % but is Nan where the the elements of the intensity mask are Nan.
            indexMatrix= reshape(1:app.Rows*app.Columns, app.Rows, app.Columns);
            indexMatrix(isnan(app.Mask)) = nan;
            % nonNanIndices is the column vector of non-Nan indices
            app.nonNanIndices = reshape(indexMatrix(not(isnan(indexMatrix))), [], 1);

            % then we perform PCA
            app.Scores = nan(app.Rows*app.Columns, app.Depth);
            [app.V, app.Scores(app.nonNanIndices, :), ~, ~, app.Explained, app.Mu] =...
                pca(datamatrix(app.nonNanIndices, :));
            % At this point app.Scores is an n*m matrix where n=rows*columns
            % and m = depth, which is Nan for the pixels that are not
            % contemplated in the intensity mask.

            % if the spectral button is pushed
            app.avgSpectrum = mean(datamatrix(app.nonNanIndices,:));
            app.avgSpectrumPCscores = app.avgSpectrum*app.V;


            % we draw the scores map according to the values of the PC spinners
            % as if pressing the Visualize button
            VisualizeButtonPushed(app, event)

            % we draw the explained variance of each PC
            explainedCumulative = zeros(app.Depth,1);
            explainedCumulative(1) = app.Explained(1);
            for i = 2:app.Depth
                explainedCumulative(i) = app.Explained(i)+explainedCumulative(i-1);
            end
            explainedCumulative = explainedCumulative/100;
            plot(app.AxesExplainedVariance, explainedCumulative, 'b-*')
            grid(app.AxesExplainedVariance, 'on');
            app.AxesExplainedVariance.YLim = [0.95*explainedCumulative(1) 1.05];
            app.AxesExplainedVariance.XLim = [0 app.Depth];

        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            % draw the scores map in the appropriate axes

            % if the sepctral mode is pressed i can add it!!! %%%%%%%%%%%%%%%%
            if app.SpectralButton.Value == 1
                app.Scores = app.Scores+app.avgSpectrumPCscores;
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            blackNan(reshape(app.Scores(:, app.PCXSpinner.Value), app.Rows, app.Columns), app.cmap, [1 1 1],app.AxPCXScoreMap)

            app.AxPCXScoreMap.XLim = [0 app.Columns];
            app.AxPCXScoreMap.YLim = [0 app.Rows];
            colormap(app.AxPCXScoreMap, app.cmap)
            colorbar(app.AxPCXScoreMap);
            clim(app.AxPCXScoreMap, [min(app.Scores(:, app.PCXSpinner.Value)) max(app.Scores(:, app.PCXSpinner.Value))])
            pbaspect(app.AxPCXScoreMap, [app.Columns, app.Rows, 1]);
            title(app.AxPCXScoreMap, strcat('PC', num2str(app.PCXSpinner.Value), ' Scores'));


            blackNan( reshape(app.Scores(:, app.PCYSpinner.Value), app.Rows, app.Columns) , app.cmap, [1 1 1],app.AxPCYScoreMap)
            app.AxPCYScoreMap.XLim = [0 app.Columns];
            app.AxPCYScoreMap.YLim = [0 app.Rows];
            colormap(app.AxPCYScoreMap, app.cmap)
            colorbar(app.AxPCYScoreMap);
            clim(app.AxPCYScoreMap, [min(app.Scores(:, app.PCYSpinner.Value)) max(app.Scores(:, app.PCYSpinner.Value))])
            pbaspect(app.AxPCYScoreMap, [app.Columns, app.Rows, 1]);
            title(app.AxPCYScoreMap, strcat('PC', num2str(app.PCYSpinner.Value), ' Scores'));


            %% Draw the corresponding PC
            pcx = app.V(:, app.PCXSpinner.Value);
            %pcx = pcx/abs(max(pcx)); % normalize the loading function
            cla(app.AxLoadingFunX)
            plot(app.AxLoadingFunX, app.Calibration, pcx)
            grid(app.AxLoadingFunX, 'on');
            title(app.AxLoadingFunX, strcat('PC', num2str(app.PCXSpinner.Value), ' Loading Function'));

            pcy = app.V(:, app.PCYSpinner.Value);
            %pcy = pcy/abs(max(pcy));
            cla(app.AxLoadingFunY)
            plot(app.AxLoadingFunY, app.Calibration, pcy)
            grid(app.AxLoadingFunY, 'on');
            title(app.AxLoadingFunY, strcat('PC', num2str(app.PCYSpinner.Value), ' Loading Function'));

            %% Draw the frequency histogram
            % % Now we draw the histogram
            histCounts = hist3( [app.Scores(:,app.PCYSpinner.Value), app.Scores(:,app.PCXSpinner.Value)],[app.Bins, app.Bins]);

            logHistCounts = zeros(size(histCounts));
            logHistCounts(histCounts>0) = log(histCounts(histCounts>0));

            ncolors = 20; % up to 256
            cmap = summer(ncolors);
            indexed_logHistCounts = uint8(ncolors * mat2gray(logHistCounts));
            rgb_logHistCounts = ind2rgb(indexed_logHistCounts, cmap);
            tmp_mask= repmat(logHistCounts, [1 1 3]);
            rgb_logHistCounts(tmp_mask==0) = 0;

            cla(app.AxPCScoresHist)
            imagesc(app.AxPCScoresHist, 'XData',...
                linspace( min(app.Scores(:,app.PCXSpinner.Value)), ...
                max(app.Scores(:,app.PCXSpinner.Value)), app.Bins),...
                'YData', linspace( min(app.Scores(:,app.PCYSpinner.Value)),...
                max(app.Scores(:,app.PCYSpinner.Value)), app.Bins),...
                'CData', rgb_logHistCounts);


            app.AxPCScoresHist.XLim = [min(app.Scores(:,app.PCXSpinner.Value)) max(app.Scores(:,app.PCXSpinner.Value))];
            app.AxPCScoresHist.YLim = [min(app.Scores(:,app.PCYSpinner.Value)) max(app.Scores(:,app.PCYSpinner.Value))];
            axis(app.AxPCScoresHist, 'square');

            xlabel(app.AxPCScoresHist, strcat('PC', num2str(app.PCXSpinner.Value)));
            ylabel(app.AxPCScoresHist, strcat('PC', num2str(app.PCYSpinner.Value)));

            % restablish the correct PC scores
            if app.SpectralButton.Value == 1
                app.Scores = app.Scores-app.avgSpectrumPCscores;
            end

        end

        % Button pushed function: SelectPixelsButton
        function SelectPixelsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SelectPixelsButton.Enable = "off";

            % Call dialog box with input values
            if app.SpectralModeON==1
                app.DialogApp = DialogBox(app, app.Intensity, app.U_matrix, app.Rows, app.Columns, app.LastPCX, app.LastPCY);
            elseif app.ClassicModeON==1
                app.DialogApp = DialogBox(app, app.Intensity, app.U_matrix_classic, app.Rows, app.Columns, app.LastPCX, app.LastPCY);
            end

        end

        % Button pushed function: SpatialAnalysisButton
        function SpatialAnalysisButtonPushed(app, event)
            %app.SpatialAnalysisButton.Enable = "off";
            SpatialAnalysis(app);
        end

        % Callback function
        function DevelopermodeButtonPushed(app, event)
            disp developer mode
        end

        % Button down function: AxThresholdMask
        function AxThresholdMaskButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureAxThresholdMask = figure;
            ExpandedAxesAxThresholdMask = copyobj(app.AxThresholdMask, FigureAxThresholdMask);
            set(ExpandedAxesAxThresholdMask, 'Units', 'normalized',...
                'Position', get(FigureAxThresholdMask, 'DefaultAxesPosition'));
        end

        % Button down function: AxPCXScoreMap
        function AxPCXScoreMapButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureAxPCXScoreMap = figure;
            ExpandedAxesAxPCXScoreMap = copyobj(app.AxPCXScoreMap, FigureAxPCXScoreMap);
            set(ExpandedAxesAxPCXScoreMap, 'Units', 'normalized',...
                'Position', get(FigureAxPCXScoreMap, 'DefaultAxesPosition'));
        end

        % Button down function: AxPCYScoreMap
        function AxPCYScoreMapButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureAxPCYScoreMap = figure;
            ExpandedAxesAxPCYScoreMap = copyobj(app.AxPCYScoreMap, FigureAxPCYScoreMap);
            set(ExpandedAxesAxPCYScoreMap, 'Units', 'normalized',...
                'Position', get(FigureAxPCYScoreMap, 'DefaultAxesPosition'));
        end

        % Button down function: AxLoadingFunX
        function AxLoadingFunXButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureAxLoadingFunX = figure;
            ExpandedAxesAxLoadingFunX = copyobj(app.AxLoadingFunX, FigureAxLoadingFunX);
            set(ExpandedAxesAxLoadingFunX, 'Units', 'normalized',...
                'Position', get(FigureAxLoadingFunX, 'DefaultAxesPosition'));
        end

        % Button down function: AxLoadingFunY
        function AxLoadingFunYButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureAxLoadingFunY = figure;
            ExpandedAxesAxLoadingFunY = copyobj(app.AxLoadingFunY, FigureAxLoadingFunY);
            set(ExpandedAxesAxLoadingFunY, 'Units', 'normalized',...
                'Position', get(FigureAxLoadingFunY, 'DefaultAxesPosition'));
        end

        % Button down function: AxPCScoresHist
        function AxPCScoresHistButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureAxPCScoresHist = figure;
            ExpandedAxesAxPCScoresHist = copyobj(app.AxPCScoresHist, FigureAxPCScoresHist);
            set(ExpandedAxesAxPCScoresHist, 'Units', 'normalized',...
                'Position', get(FigureAxPCScoresHist, 'DefaultAxesPosition'));
        end

        % Button down function: AxesExplainedVariance
        function AxesExplainedVarianceButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureAxesExplainedVariance = figure;
            ExpandedAxesExplainedVariance = copyobj(app.AxesExplainedVariance, FigureAxesExplainedVariance);
            set(ExpandedAxesExplainedVariance, 'Units', 'normalized',...
                'Position', get(FigureAxesExplainedVariance, 'DefaultAxesPosition'));
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            SaveData(app, 0, 0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.AxIntensityHistogram = uiaxes(app.UIFigure);
            title(app.AxIntensityHistogram, 'Intensity Histogram')
            xlabel(app.AxIntensityHistogram, 'Intensity')
            ylabel(app.AxIntensityHistogram, '# of pixels')
            zlabel(app.AxIntensityHistogram, 'Z')
            app.AxIntensityHistogram.YScale = 'log';
            app.AxThresholdMask = uiaxes(app.UIFigure);
            title(app.AxThresholdMask, 'Threshold Mask')
            zlabel(app.AxThresholdMask, 'Z')
            app.AxThresholdMask.ButtonDownFcn = createCallbackFcn(app, @AxThresholdMaskButtonDown, true);
            app.AxPCXScoreMap = uiaxes(app.UIFigure);
            title(app.AxPCXScoreMap, ' PCX Scores')
            zlabel(app.AxPCXScoreMap, 'Z')
            app.AxPCXScoreMap.ButtonDownFcn = createCallbackFcn(app, @AxPCXScoreMapButtonDown, true);
            app.AxLoadingFunX = uiaxes(app.UIFigure);
            title(app.AxLoadingFunX, 'PCX Loading Function')
            xlabel(app.AxLoadingFunX, 'X')
            zlabel(app.AxLoadingFunX, 'Z')
            app.AxLoadingFunX.ButtonDownFcn = createCallbackFcn(app, @AxLoadingFunXButtonDown, true);
            app.AxPCYScoreMap = uiaxes(app.UIFigure);
            title(app.AxPCYScoreMap, 'PCY Scores')
            zlabel(app.AxPCYScoreMap, 'Z')
            app.AxPCYScoreMap.ButtonDownFcn = createCallbackFcn(app, @AxPCYScoreMapButtonDown, true);
            app.AxLoadingFunY = uiaxes(app.UIFigure);
            title(app.AxLoadingFunY, 'PCY Loading Function')
            xlabel(app.AxLoadingFunY, 'X')
            zlabel(app.AxLoadingFunY, 'Z')
            app.AxLoadingFunY.ButtonDownFcn = createCallbackFcn(app, @AxLoadingFunYButtonDown, true);
            app.AxPCScoresHist = uiaxes(app.UIFigure);
            title(app.AxPCScoresHist, 'Frequency Histogram')
            xlabel(app.AxPCScoresHist, 'PCX')
            ylabel(app.AxPCScoresHist, 'PCY')
            zlabel(app.AxPCScoresHist, 'Z')
            app.AxPCScoresHist.ButtonDownFcn = createCallbackFcn(app, @AxPCScoresHistButtonDown, true);
            app.AxIntensityMap = uiaxes(app.UIFigure);
            title(app.AxIntensityMap, 'Intensity Map')
            zlabel(app.AxIntensityMap, 'Z')
            app.AxesExplainedVariance = uiaxes(app.UIFigure);
            title(app.AxesExplainedVariance, 'Explained Variance')
            xlabel(app.AxesExplainedVariance, 'PC number')
            zlabel(app.AxesExplainedVariance, 'Z')
            app.AxesExplainedVariance.ButtonDownFcn = createCallbackFcn(app, @AxesExplainedVarianceButtonDown, true);
            app.PCXSpinnerLabel = uilabel(app.UIFigure);
            app.PCXSpinnerLabel.Text = 'PCX';
            app.PCXSpinner = uispinner(app.UIFigure);
            app.PCXSpinner.Limits = [1 Inf];
            app.PCXSpinner.RoundFractionalValues = 'on';
            app.PCXSpinner.Value = 1;
            app.PCYSpinnerLabel = uilabel(app.UIFigure);
            app.PCYSpinnerLabel.Text = 'PCY';
            app.PCYSpinner = uispinner(app.UIFigure);
            app.PCYSpinner.Limits = [1 Inf];
            app.PCYSpinner.Value = 2;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold';
            app.IntensityThresholdSlider = uislider(app.UIFigure);
            app.IntensityThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @IntensityThresholdSliderValueChanged, true);
            app.IntensityThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @IntensityThresholdSliderValueChanging, true);
            app.SelectPixelsButton = uibutton(app.UIFigure, 'push');
            app.SelectPixelsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPixelsButtonPushed, true);
            app.SelectPixelsButton.Text = 'Select Pixels';
            app.LoadCalibrationButton = uibutton(app.UIFigure, 'push');
            app.LoadCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationButtonPushed, true);
            app.LoadCalibrationButton.Enable = 'off';
            app.LoadCalibrationButton.Text = 'Load Calibration';
            app.SpatialAnalysisButton = uibutton(app.UIFigure, 'push');
            app.SpatialAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SpatialAnalysisButtonPushed, true);
            app.SpatialAnalysisButton.Text = 'Spatial Analysis';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ClassicButton = uiradiobutton(app.ButtonGroup);
            app.ClassicButton.Text = 'Classic (center PC)';
            app.ClassicButton.Value = true;
            app.SpectralButton = uiradiobutton(app.ButtonGroup);
            app.SpectralButton.Text = 'Spectral (non-centered)';
            app.PerformPCAButton = uibutton(app.ButtonGroup, 'push');
            app.PerformPCAButton.ButtonPushedFcn = createCallbackFcn(app, @PerformPCAButtonPushed, true);
            app.PerformPCAButton.Text = 'Perform PCA';
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCA_imaging

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end