classdef SetThresholdLocalMoran < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RecommendedThresholdEditField  matlab.ui.control.NumericEditField
        RecommendedThresholdEditFieldLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        SetMaskButton                  matlab.ui.control.Button
        SignificantPixelsEditField     matlab.ui.control.NumericEditField
        SignificantPixelsLabel         matlab.ui.control.Label
        ThresholdValueEditField        matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel   matlab.ui.control.Label
        SetThresholdEditField          matlab.ui.control.NumericEditField
        SetThresholdEditFieldLabel     matlab.ui.control.Label
        ThresholdSlider                matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        AxesMoranScatterplot           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MaxI_local

        maxThreshold 

        x_negative
        x_positive
        
        % objects
        init_hypbranch % used for initialization and legend
        hypbranch1
        hypbranch2
        hypbranch3
        hypbranch4

        indicesOfSignificantPixels

        ActiveTab
        IndexOfActiveTab

        tmpscatter

    end
    
    properties (Access = private)
        MainApp % 
        SpatialAnalysisCaller 
        

        ScatterplotHandle

        SignificantPixels;
        SignificantSpatialLag;

        TotalSignificantPixels

        sp  % initialization placeholder value
        sp2 % Scatterplots in the PC space 
        sp3

        allPixelsScatterplot

        I_global_line

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, mainapp, activeTab)

            app.ActiveTab = activeTab;
            app.SpatialAnalysisCaller = caller;            
            app.MainApp = mainapp; 

            % we find the index of the active tab. 
            % Example: if the first tab is PC2, then its index is 1           
            app.IndexOfActiveTab = find(app.SpatialAnalysisCaller.LastPCSubspace == activeTab);

            % initialization of graphical elements
            app.sp = scatter(app.AxesMoranScatterplot, 0,0, 3,'k', 'filled');
            hold(app.AxesMoranScatterplot, 'on')
            
            %initialization of the hyperbole branches as objects
            app.init_hypbranch = plot(app.AxesMoranScatterplot, 0,0, '-b');
            app.hypbranch1 = app.sp;
            %app.hypbranch2 = app.sp; % we don't want branches in the 2nd
                                      % and 4th quadrant          
            app.hypbranch3 = app.sp;
            %app.hypbranch4 = app.sp;
            app.tmpscatter = app.sp;
            
            %%%%%% initialization to check
            app.sp3 = app.sp;
            app.sp2 = app.sp;
            
            
            % Draw the Moran Scatterplot on the Axes
            [app.ScatterplotHandle, app.I_global_line] = DrawMoranScatterplot(app.SpatialAnalysisCaller.PixelPC{app.IndexOfActiveTab}, ...
                app.SpatialAnalysisCaller.SpatialLag{app.IndexOfActiveTab},...
                app.SpatialAnalysisCaller.I_global{app.IndexOfActiveTab},app.AxesMoranScatterplot) ; 
            app.ScatterplotHandle.CData = 0.6*[1 1 1];
            pbaspect(app.AxesMoranScatterplot, [1 1 1])


            app.MaxI_local = max(max(app.SpatialAnalysisCaller.I_local{app.IndexOfActiveTab}));
            

            % % Deafault initialization of threshold slider value
            app.ThresholdSlider.Value = 0;

            app.x_negative = linspace(app.AxesMoranScatterplot.XLim(1), -eps);
            app.x_positive = linspace(eps, app.AxesMoranScatterplot.XLim(2));

            app.SignificantPixels = app.SpatialAnalysisCaller.PixelPC{app.IndexOfActiveTab};
            app.SignificantSpatialLag = app.SpatialAnalysisCaller.SpatialLag{app.IndexOfActiveTab};

            % add legend
            handles = [app.sp, app.ScatterplotHandle, app.I_global_line, app.init_hypbranch];
            labels = {"Significant pixels", "Non-significant pixels","I_{global}="+num2str(app.SpatialAnalysisCaller.I_global{app.IndexOfActiveTab}, 3), "I_{local} threshold"};
            lgd = legend(app.AxesMoranScatterplot, handles, labels, 'Location', 'northeastoutside');
            lgd.AutoUpdate = 'off';

            hold(app.AxesMoranScatterplot, 'off')
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;

            interactive_threshold = abs(value*app.MaxI_local/100 ); 

            delete(app.sp)

            % find and draw the significant pixels in black
            hold(app.AxesMoranScatterplot, 'on');
            app.indicesOfSignificantPixels = app.SpatialAnalysisCaller.I_local{app.IndexOfActiveTab} >= interactive_threshold;
            app.sp = scatter(app.AxesMoranScatterplot,app.SignificantPixels(app.indicesOfSignificantPixels), app.SignificantSpatialLag(app.indicesOfSignificantPixels),...
                 3, 'k', 'filled');

            % draw the hyperbole branches
            delete([app.hypbranch1, app.hypbranch3])
            app.hypbranch1 = plot(app.AxesMoranScatterplot, app.x_negative, interactive_threshold*(app.x_negative.^-1), '-b');
            %app.hypbranch2 = plot(app.AxesMoranScatterplot, app.x_negative, abs( interactive_threshold*(app.x_negative.^-1)), '-b');
            app.hypbranch3 = plot(app.AxesMoranScatterplot, app.x_positive, interactive_threshold*(app.x_positive.^-1), '-b');
            %app.hypbranch4 = plot(app.AxesMoranScatterplot, app.x_positive, -1*( interactive_threshold*(app.x_positive.^-1)), '-b');   

            % set the SetMask button to ON 
            set(app.SetMaskButton, 'Enable', 'on');

            % build the legend
            handles = [app.sp, app.ScatterplotHandle, app.I_global_line, app.init_hypbranch];
            labels = {"Significant pixels", "Non-significant pixels","I_{global}="+num2str(app.SpatialAnalysisCaller.I_global{app.IndexOfActiveTab}, 3), "I_{local} threshold"};
            lgnd = legend(app.AxesMoranScatterplot, handles, labels, 'Location', 'northeastoutside');
            lgnd.AutoUpdate = 'off';

            % update the values in the other fields of the app
            app.SetThresholdEditField.Value = str2double(sprintf('%.2f', value));
            app.ThresholdValueEditField.Value = str2double(sprintf('%.2f', interactive_threshold));

            % update the axes in the caller app Spatial Analysis
            % delete(app.tmpscatter)
            % app.tmpscatter = scatter(app.SpatialAnalysisCaller.axesMoranScatterplotHandles{app.IndexOfActiveTab},...
            %     app.SignificantPixels(app.indicesOfSignificantPixels), app.SignificantSpatialLag(app.indicesOfSignificantPixels),...
            %      3, 'k', 'filled');
            app.SpatialAnalysisCaller.updateMoranScatterplot(...
                app.IndexOfActiveTab, app.indicesOfSignificantPixels)

            %hold(app.AxesMoranScatterplot, 'off');


        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            value = event.Value;
          
            interactive_threshold = abs(value*app.MaxI_local/100 ); 

            delete(app.sp)

            % find and draw the significant pixels in black
            hold(app.AxesMoranScatterplot, 'on');
            app.indicesOfSignificantPixels = app.SpatialAnalysisCaller.I_local{app.IndexOfActiveTab} >= interactive_threshold;
            app.sp = scatter(app.AxesMoranScatterplot,app.SignificantPixels(app.indicesOfSignificantPixels), app.SignificantSpatialLag(app.indicesOfSignificantPixels),...
                 3, 'k', 'filled');

            % draw the hyperbole branches
            delete([app.hypbranch1, app.hypbranch3])
            app.hypbranch1 = plot(app.AxesMoranScatterplot, app.x_negative, interactive_threshold*(app.x_negative.^-1), '-b');
            %.hypbranch2 = plot(app.AxesMoranScatterplot, app.x_negative, abs( interactive_threshold*(app.x_negative.^-1)), '-b');
            app.hypbranch3 = plot(app.AxesMoranScatterplot, app.x_positive, interactive_threshold*(app.x_positive.^-1), '-b');
            %app.hypbranch4 = plot(app.AxesMoranScatterplot, app.x_positive, -1*( interactive_threshold*(app.x_positive.^-1)), '-b');  
            
            % build the legend
            % handles = [app.sp, app.ScatterplotHandle, app.I_global_line, app.init_hypbranch];
            % labels = {"Significant pixels", "Non-significant pixels","I_{global}="+num2str(app.SpatialAnalysisCaller.I_global{app.IndexOfActiveTab}, 3), "I_{local} threshold"};
            % lgnd = legend(app.AxesMoranScatterplot, handles, labels, 'Location', 'northeastoutside');
            % lgnd.AutoUpdate = 'off';

            % update the values in the other fields of the app
            app.SetThresholdEditField.Value = str2double(sprintf('%.2f', value));
            app.ThresholdValueEditField.Value = str2double(sprintf('%.2f', interactive_threshold));

            % update the axes in the caller app Spatial Analysis
            app.SpatialAnalysisCaller.updateMoranScatterplot(...
                app.IndexOfActiveTab, app.indicesOfSignificantPixels)

            %hold(app.AxesMoranScatterplot, 'off');
            
        end

        % Value changed function: SetThresholdEditField
        function SetThresholdEditFieldValueChanged(app, event)
            value = app.SetThresholdEditField.Value; 
            
            if value <=101
                app.ThresholdSlider.Value = value;
            elseif value >101
                app.ThresholdSlider.Value = 101;
            end

            ThresholdSliderValueChanged(app, event)
         
        end

        % Value changed function: ThresholdValueEditField
        function ThresholdValueEditFieldValueChanged(app, event)
            value = app.ThresholdValueEditField.Value;

            if value <= app.MaxI_local
                app.ThresholdSlider.Value = (value/app.MaxI_local)*100;
            elseif value > 1.01*app.MaxI_local
                app.ThresholdValueEditField.Value = 1.01*app.MaxI_local;
                app.ThresholdSlider.Value = 101;
            end

            ThresholdSliderValueChanged(app, event)
           
        end

        % Button pushed function: SetMaskButton
        function SetMaskButtonPushed(app, event)
            app.SpatialAnalysisCaller.SignificantPixelsMask{app.IndexOfActiveTab} = app.indicesOfSignificantPixels;
            
            % Temporary variable that represents all the significant pixels
            % I visualize. It's the sum of all the significant pixels for
            % each PC.
            tempSignificantPixelsMask = zeros(app.MainApp.Rows, app.MainApp.Columns);
            for i = 1:length(app.SpatialAnalysisCaller.SignificantPixelsMask)
                tempSignificantPixelsMask = tempSignificantPixelsMask+app.SpatialAnalysisCaller.SignificantPixelsMask{i};
            end
            imagesc(app.SpatialAnalysisCaller.AxesSignificantMap, tempSignificantPixelsMask>0);


            %these are the pixels to draw in the scatterplot
            app.TotalSignificantPixels = tempSignificantPixelsMask>0;  % Condition for OR mask
            % totalSignificantPixels = sum(app.SpatialAnalysisCaller.SignificantPixelsMask,3)==dimensions; % condition for AND mask
            app.SpatialAnalysisCaller.ofsignificantpixelsEditField.Value = sum(sum(app.TotalSignificantPixels)); % check !!
    
            % tempPixelPC = app.SpatialAnalysisCaller.PixelPC(:,:,app.IndexOfActiveTab);
            % imagesc(app.SpatialAnalysisCaller.AxesSignificantScores, tempPixelPC())

            iX = find(app.SpatialAnalysisCaller.PCspaceListBox.ItemsData == app.SpatialAnalysisCaller.PCXSpinner.Value);
            iY = find(app.SpatialAnalysisCaller.PCspaceListBox.ItemsData == app.SpatialAnalysisCaller.PCYSpinner.Value);
            iZ = find(app.SpatialAnalysisCaller.PCspaceListBox.ItemsData == app.SpatialAnalysisCaller.PCZSpinner.Value);

            %if app.SpatialAnalysisCaller.PCXSpinner.Value > 0 && app.SpatialAnalysisCaller.PCYSpinner.Value > 0 && app.SpatialAnalysisCaller.PCZSpinner.Value > 0
            if isempty(iZ) && isempty(iY)
                cla(app.SpatialAnalysisCaller.AxesSignificantScores)
    
                app.sp2 = scatter(app.SpatialAnalysisCaller.AxesSignificantScores, ...
                    app.SpatialAnalysisCaller.PixelPC{iX}(app.TotalSignificantPixels),...
                    app.SpatialAnalysisCaller.PixelPC{iX}(app.TotalSignificantPixels), '.k');

            elseif isempty(iZ) && not(isempty(iY))
      
                cla(app.SpatialAnalysisCaller.AxesSignificantScores)
    
                app.sp2 = scatter(app.SpatialAnalysisCaller.AxesSignificantScores, ...
                    app.SpatialAnalysisCaller.PixelPC{iX}(app.TotalSignificantPixels),...
                    app.SpatialAnalysisCaller.PixelPC{iY}(app.TotalSignificantPixels), '.k');
            elseif iX > 0 && iY > 0 && iZ > 0
                
                cla(app.SpatialAnalysisCaller.AxesSignificantScores)
  
                app.sp3 = scatter3(app.SpatialAnalysisCaller.AxesSignificantScores, ...
                    app.SpatialAnalysisCaller.PixelPC{iX}(app.TotalSignificantPixels),...
                    app.SpatialAnalysisCaller.PixelPC{iY}(app.TotalSignificantPixels),...
                    app.SpatialAnalysisCaller.PixelPC{iZ}(app.TotalSignificantPixels), '.k');
            end

            app.SpatialAnalysisCaller.AllSignificantPixelsMap = app.TotalSignificantPixels; % update the AllSignificantPixelsMap in the Spatial Analysis caller


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            I_local_vec = app.SpatialAnalysisCaller.I_local{app.IndexOfActiveTab}(app.SpatialAnalysisCaller.I_local{app.IndexOfActiveTab}>0);
            [desc_I_local, desc_idx_I_local] = sort(I_local_vec, "descend");

            [E_tot, ~] = entropyRainfall(app.MainApp.Mask,desc_idx_I_local);
            [x_elbow, x_elbow_idx] = elbow_pt(desc_I_local,E_tot,1);

            app.RecommendedThresholdEditField.Value = x_elbow;

        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.ThresholdSlider.Value = (app.RecommendedThresholdEditField.Value/app.MaxI_local)*100;
            ThresholdSliderValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxesMoranScatterplot = uiaxes(app.UIFigure);
            title(app.AxesMoranScatterplot, 'Title')
            xlabel(app.AxesMoranScatterplot, 'X')
            ylabel(app.AxesMoranScatterplot, 'Y')
            zlabel(app.AxesMoranScatterplot, 'Z')
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 101];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.SetThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.SetThresholdEditFieldLabel.Text = 'Set Threshold % ';
            app.SetThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SetThresholdEditFieldValueChanged, true);
            app.ThresholdValueEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditFieldValueChanged, true);
            app.SignificantPixelsLabel = uilabel(app.UIFigure);
            app.SignificantPixelsLabel.Text = '# Significant Pixels';
            app.SignificantPixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignificantPixelsEditField.Editable = 'off';
            app.SetMaskButton = uibutton(app.UIFigure, 'push');
            app.SetMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SetMaskButtonPushed, true);
            app.SetMaskButton.Text = 'Set Mask';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RecommendedThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.RecommendedThresholdEditFieldLabel.Text = 'Recommended Threshold ';
            app.RecommendedThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetThresholdLocalMoran(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end