classdef SpatialAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        MaskMenu_2                    matlab.ui.container.Menu
        ofsignificantpixelsEditField  matlab.ui.control.NumericEditField
        ofsignificantpixelsEditFieldLabel  matlab.ui.control.Label
        FindSimilarButton             matlab.ui.control.Button
        ClusterButton                 matlab.ui.control.Button
        VisualizeButton               matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        PCTabGroup                    matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        SetthresholdforsignificantpixelsButton  matlab.ui.control.Button
        PCZSpinnerLabel               matlab.ui.control.Label
        PCSpinner                     matlab.ui.control.Spinner
        PCSpinnerLabel                matlab.ui.control.Label
        PCspaceListBox                matlab.ui.control.ListBox
        PCspaceListBoxLabel           matlab.ui.control.Label
        PCXSpinner                    matlab.ui.control.Spinner
        PCYSpinner                    matlab.ui.control.Spinner
        PCYSpinnerLabel               matlab.ui.control.Label
        PCXSpinnerLabel               matlab.ui.control.Label
        PCZSpinner                    matlab.ui.control.Spinner
        AddPCButton                   matlab.ui.control.Button
        CalculateMoransIButton        matlab.ui.control.Button
        ApplyMaskButton               matlab.ui.control.Button
        SaveMaskButton                matlab.ui.control.Button
        AxesSignificantMap            matlab.ui.control.UIAxes
        AxesSignificantScores         matlab.ui.control.UIAxes
        ContextMenuPCListBox          matlab.ui.container.ContextMenu
        Delete                        matlab.ui.container.Menu
        ContextMenuSignificantPixelsMap  matlab.ui.container.ContextMenu
        ExpandMenu                    matlab.ui.container.Menu
    end

    
    properties (Access = public)
        
        
        Counter = 0;  % Counts the number of PCs and serves as index of the PCs
        CurrentPC = 1; % It's the number of the PC of the current tab
        PCSubspace = [] % Vector which contains the indices of the PCs used for the subspace
        I_local_thresholds;
        PixelPC; % The scores of the pixels
        SpatialLag;

        I_local; % list of matrices which contain the I_locals of each pixel
        I_global; % vector which contains the I_global of each PC used
        
                
        SignificantPixelsMask = {}; % initialize as empty cell

        LastPCSubspace = [] % This vector contains the values of the PCs for which
                           % we already computed the Moran's I 

       %IsRunningSTLMFlag = [] % this vector tells me which instances of 
                               % SetThresholdLocalMoran are running


        AllSignificantPixelsMap

        axesMoranScatterplotHandles % collects the handles to the axes of Moran scatterplots        
        MoranScatterplotPixelsHandles % collects the handles to the pixels of Moran scatterplots
        I_global_line_handles % collects the handles to the straight red lines of Moran scatterplots,
                              % whose angular coeff is the I_global
        lgd % collects the handles to the legends of the Moran Scatterplots
        
        significantPixelsMoranScatterplot % collects the scatterplot of the
                                          % significant pixels

        NumberOfSignificantPixels % how many significant pixels there are

        % Other Apps
        SetThresholdLocalMoran;

 
    end
    
    properties (Access = private)
        MainApp 
    end
    
    methods (Access = public)

        function updateMoranScatterplot(app, idx, indicesOfSignificantPixels)
             delete(app.significantPixelsMoranScatterplot{idx})
             app.significantPixelsMoranScatterplot{idx} = scatter(app.axesMoranScatterplotHandles{idx},...
                 app.PixelPC{idx}(indicesOfSignificantPixels), app.SpatialLag{idx}(indicesOfSignificantPixels),...
                 3, 'k', 'filled');   
             % update the legend
             %tmp = "I_{global}="+num2str(app.I_global{idx}, 3);
             app.lgd{idx} = legend( app.axesMoranScatterplotHandles{idx},...
                [ app.significantPixelsMoranScatterplot{idx},...
                  app.MoranScatterplotPixelsHandles{idx},...
                  app.I_global_line_handles{idx}    ], ...
                { "Significant pixels", ...
                  "Non-significant pixels", ...
                  "I_{global}="+num2str(app.I_global{idx}, 3)   }, ...
                  'Location', 'northeastoutside', 'AutoUpdate','off');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.PCspaceListBox.Items = {};  
            
            % This is used to delete the first placeholder element of the listbox
            if ~isempty(app.PCTabGroup.Children)
                delete(app.PCTabGroup.Children(1));
            end
            
            placeholderMask = zeros(app.MainApp.Rows,app.MainApp.Columns);
        
            imagesc(app.AxesSignificantMap, placeholderMask);
            daspect(app.AxesSignificantMap, [app.MainApp.Rows app.MainApp.Columns 1])
            app.AxesSignificantMap.XLim = [0 app.MainApp.Columns];
            app.AxesSignificantMap.YLim = [0 app.MainApp.Rows];

        end

        % Value changed function: PCSpinner
        function PCSpinnerValueChanged(app, event)
            value = app.PCSpinner.Value;
            app.CurrentPC = value;
        end

        % Button pushed function: AddPCButton
        function AddPCButtonPushed(app, event)
            newItemName = strcat('PC', num2str(app.CurrentPC));

            %if the item name already exists, then do nothing, else save a
            % new item and create a new mask
            if sum(strcmp(app.PCspaceListBox.Items, newItemName))==0
                app.PCspaceListBox.Items(end+1) = {newItemName};
                app.PCspaceListBox.ItemsData(end+1) = app.CurrentPC; % we save the order of the current PC in
                app.PCSubspace(end+1) = app.CurrentPC ; % we could only use the ItemsData property of the listbox
                app.Counter = app.Counter+1;
                
                % We build the mask matrices as cells
                % if app.SignificantPixelsMask is an empty cell array 
                if isempty(app.SignificantPixelsMask)
                    app.SignificantPixelsMask{1} = zeros(app.MainApp.Rows, app.MainApp.Columns);
                    %app.SignificantPixelsMask{1}(app.MainApp.Mask == 1) = 1;
                else
                    app.SignificantPixelsMask{end+1} = zeros(app.MainApp.Rows, app.MainApp.Columns);
                end

            end

        end

        % Button pushed function: CalculateMoransIButton
        function CalculateMoransIButtonPushed(app, event)
            rows = app.MainApp.Rows;
            columns = app.MainApp.Columns;
            dimensions = length(app.PCspaceListBox.ItemsData);
            
            %tabHandles = cell(1, dimensions);
            %app.axesMoranScatterplotHandles = cell(1, dimensions);

            coeff = [0 1];
            weights = matryoshka("odd", coeff);
            
            % for every PC in PCSubspace we create a tab with axes inside
            % and we compute the spatial lag, the local and global Moran's
            % I 
            for i = 1:dimensions
                [~, indexToAdd] =  ismember(app.PCspaceListBox.ItemsData(i), app.LastPCSubspace);
                % if the PC in the current list box is NOT among those for
                % which we already calculated the Moran's I and created a
                % mask, then we create a new tab and another empty mask
                if indexToAdd == 0
                     PCTab = uitab(app.PCTabGroup, "Title", strcat("PC", ...
                         num2str(app.PCspaceListBox.ItemsData(i)))); % create tabs
                     tabHandle{i} = PCTab;
    
                     axesMoranScatterplot = uiaxes(PCTab, ...
                         "OuterPosition", 0.85*app.PCTabGroup.Position); % create axes in tab
                     app.axesMoranScatterplotHandles{i} = axesMoranScatterplot;

                     % app.PixelPC{i} is the i-th face of the datacube with
                     % the scores, with Nans where the pixels are Nan
                     app.PixelPC{i} = reshape(app.MainApp.Scores(:,app.PCspaceListBox.ItemsData(i)), app.MainApp.Rows, app.MainApp.Columns);
                     

                     [app.I_global{i}, app.I_local{i}, app.SpatialLag{i}, app.PixelPC{i}] = morans_I(app.PixelPC{i} , weights);
                     
                     % we draw the corresponding Moran Scatterplot in each of the axes
                     app.significantPixelsMoranScatterplot{i} = scatter(app.axesMoranScatterplotHandles{i},...
                         0,0, 3,'k', 'filled');
                     hold(app.axesMoranScatterplotHandles{i}, 'on')

                     [app.MoranScatterplotPixelsHandles{i}, app.I_global_line_handles{i}] = DrawMoranScatterplot(...
                         app.PixelPC{i}, app.SpatialLag{i}, app.I_global{i}, app.axesMoranScatterplotHandles{i});
                     app.MoranScatterplotPixelsHandles{i}.CData = 0.6*[1 1 1];

                     pbaspect(app.axesMoranScatterplotHandles{i}, [1 1 1]);

                    % we add the legend to each scatterplot
                    app.lgd{i} = legend( app.axesMoranScatterplotHandles{i},...
                        [ app.significantPixelsMoranScatterplot{i},...
                          app.MoranScatterplotPixelsHandles{i},...
                          app.I_global_line_handles{i}], ...
                          { "Significant pixels", ...
                          "Non-significant pixels", ...
                          "I_{global}="+num2str(app.I_global{i}, 3)}, ...
                          'Location', 'northeastoutside', 'AutoUpdate','off');     


                end
            end

            % Update the LastPCSubspace vector for future calculations
            app.LastPCSubspace = app.PCspaceListBox.ItemsData;

            for i = 1:length(app.PCspaceListBox.ItemsData)
                switch i
                    case 1
                       app.PCXSpinner.Value = app.PCspaceListBox.ItemsData(i);
                    case 2
                       app.PCYSpinner.Value = app.PCspaceListBox.ItemsData(i);
                    case 3
                       app.PCZSpinner.Value = app.PCspaceListBox.ItemsData(i);
                end
            end


        end

        % Menu selected function: Delete
        function DeleteMenuSelected(app, event)
           % This function deletes the selected PC component from the
           % listbox items and the tabs and from the masks.
           % First find the index of the selected PC component among the
           % items in the listbox
           [~, indexToDelete] =  ismember(app.PCspaceListBox.Value, app.PCspaceListBox.ItemsData);
           
           % Then for all tabs in the tab group, check whether the tab with the
           % corresponding title to be deleted is there and delete it
            for i = 1:length(app.PCTabGroup.Children)
                if strcmp(app.PCspaceListBox.Items(indexToDelete), app.PCTabGroup.Children(i).Title)
                    delete(app.PCTabGroup.Children(i));
                    % delete the corresponding I global and I local, PC 
                    % scores and spatial lag as well
                    app.I_local(i) = [];
                    app.I_global(i) = [];
                    app.SpatialLag(i) = [];
                    app.PixelPC(i) = [];

                    app.lgd(i) = []; %?
                    app.significantPixelsMoranScatterplot(i) = []; %?
                    app.axesMoranScatterplotHandles(i) = []; %?
                    app.I_global_line_handles(i) = [];
                    app.MoranScatterplotPixelsHandles(i) = [];
                    break
                end
            end          
           
           % Delete the corresponding layer of the mask
           app.SignificantPixelsMask(indexToDelete) = [];

           % Delete the entry in the LastPCSubspace vector
           [~, indexToDeleteLAST] =  ismember(app.PCspaceListBox.Value, app.LastPCSubspace);
           if indexToDeleteLAST>0
                app.LastPCSubspace(indexToDeleteLAST) = [];
           end
     
           % Finally delete the item and its ItemData in the listbox
           app.PCspaceListBox.Items(indexToDelete) = [];
           app.PCspaceListBox.ItemsData(indexToDelete) = [];

        % Reset the values of the spinners for visualization
        for i = 1:length(app.PCspaceListBox.ItemsData)
            switch i
                case 1
                   app.PCXSpinner.Value = app.PCspaceListBox.ItemsData(i);
                case 2
                   app.PCYSpinner.Value = app.PCspaceListBox.ItemsData(i);
                case 3
                   app.PCZSpinner.Value = app.PCspaceListBox.ItemsData(i);
                otherwise
                    break
            end
        end

        % if the elements of PCspaceListBox are less than 3, set the
        % appropriate spinners to 0
        if i == 1
            app.PCYSpinner.Value = 0;
            app.PCZSpinner.Value = 0;
        elseif i == 2
            app.PCZSpinner.Value = 0;
        elseif isempty(i)  % it means that all elements of the listbox have been deleted
            app.PCXSpinner.Value = 0;
            app.PCYSpinner.Value = 0;
            app.PCZSpinner.Value = 0;
        end

        % update the significant pixels MAP axes
        app.AllSignificantPixelsMap = zeros(app.MainApp.Rows, app.MainApp.Columns);
            for i = 1:length(app.SignificantPixelsMask)
                app.AllSignificantPixelsMap = app.AllSignificantPixelsMap+app.SignificantPixelsMask{i};
            end
            app.AllSignificantPixelsMap = app.AllSignificantPixelsMap>0; % transform into logical
            imagesc(app.AxesSignificantMap, app.AllSignificantPixelsMap);
            app.NumberOfSignificantPixels = sum(sum(app.AllSignificantPixelsMap));
            app.ofsignificantpixelsEditField.Value = app.NumberOfSignificantPixels;


        end

        % Button pushed function: SetthresholdforsignificantpixelsButton
        function SetthresholdforsignificantpixelsButtonPushed(app, event)
            activeTab = str2double(erase(app.PCTabGroup.SelectedTab.Title, 'PC'));
            SetThresholdLocalMoran(app,app.MainApp, activeTab);
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            spinnerValues = [app.PCXSpinner.Value app.PCYSpinner.Value app.PCZSpinner.Value];

            % reorder in such a way that the zeros stay at the end and
            % assign the reordered values to the spinners
            spinnerValues = [spinnerValues(spinnerValues~=0), spinnerValues(spinnerValues==0)];
            for i = 1:3
              switch i
                case 1
                   app.PCXSpinner.Value = spinnerValues(i);
                case 2
                   app.PCYSpinner.Value = spinnerValues(i);
                case 3
                   app.PCZSpinner.Value = spinnerValues(i);
              end
            end
           
            
            % if all the nonzero spinners belong to the LastPCSubspace
            % vector, meaning we have already calculated Moran's I for
            % them, we can plot them.
            % If one of the spinnerValues does not appear in LastPCSubspace
            % then ismember returns a vector with 0 in that position
            if sum( ismember( spinnerValues(spinnerValues~=0), app.LastPCSubspace ) == 0 ) == 0
                % if we are here then all the spinner values appear in
                % LastPCSubspace
                
                    app.AllSignificantPixelsMap = zeros(app.MainApp.Rows, app.MainApp.Columns);
                    for i = 1:length(app.SignificantPixelsMask)
                        app.AllSignificantPixelsMap = app.AllSignificantPixelsMap+app.SignificantPixelsMask{i};
                    end
                    % check if correct
                    app.AllSignificantPixelsMap = app.AllSignificantPixelsMap>0;
                    app.NumberOfSignificantPixels = sum(sum(app.AllSignificantPixelsMap));
                    app.ofsignificantpixelsEditField.Value = app.NumberOfSignificantPixels;


                if length(spinnerValues(spinnerValues~=0)) == 1
                    warndlg("Please add another PC.")
                elseif length(spinnerValues(spinnerValues~=0)) == 2
                    iX = find(app.LastPCSubspace == app.PCXSpinner.Value );
                    iY = find(app.LastPCSubspace == app.PCYSpinner.Value );                    
                    cla(app.AxesSignificantScores)
                  scatter(app.AxesSignificantScores, ...
                    app.PixelPC{iX}(app.AllSignificantPixelsMap>0),...
                    app.PixelPC{iY}(app.AllSignificantPixelsMap>0), '.k');
                  xlabel(app.AxesSignificantScores, strcat('PC', num2str(app.PCXSpinner.Value)));
                  ylabel(app.AxesSignificantScores, strcat('PC', num2str(app.PCYSpinner.Value)));
                  view(app.AxesSignificantScores, 2)
                  % call function to expand the figure
                  AxesSignificantScoresButtonDown(app,event)

                elseif length(spinnerValues(spinnerValues~=0)) == 3
                    iX = find(app.LastPCSubspace == app.PCXSpinner.Value );
                    iY = find(app.LastPCSubspace == app.PCYSpinner.Value );
                    iZ = find(app.LastPCSubspace == app.PCZSpinner.Value );
                   cla(app.AxesSignificantScores)
                  scatter3(app.AxesSignificantScores, ...
                    app.PixelPC{iX}(app.AllSignificantPixelsMap>0),...
                    app.PixelPC{iY}(app.AllSignificantPixelsMap>0),...
                    app.PixelPC{iZ}(app.AllSignificantPixelsMap>0), '.k');
                  % title(app.AxesSignificantScores, 'Significant Pixels in PC Space')
                   xlabel(app.AxesSignificantScores, strcat('PC', num2str(app.PCXSpinner.Value)));
                   ylabel(app.AxesSignificantScores, strcat('PC', num2str(app.PCYSpinner.Value)));
                   zlabel(app.AxesSignificantScores, strcat('PC', num2str(app.PCZSpinner.Value)));
                  view(app.AxesSignificantScores, 3)                    
                  % call function to expand the figure
                  AxesSignificantScoresButtonDown(app,event)

                end           
            else
                warndlg("Please ensure that you have calculated Moran's I " + ...
                    "for all the PCs you wish to visualize.")
            end

            
        end

        % Button down function: AxesSignificantMap
        function AxesSignificantMapButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureSignificantMap = figure;
            ExpandedAxesSignificantMap = copyobj(app.AxesSignificantMap, FigureSignificantMap);
            set(ExpandedAxesSignificantMap, 'Units', 'normalized',...
                'Position', get(FigureSignificantMap, 'DefaultAxesPosition'));

        end

        % Button down function: AxesSignificantScores
        function AxesSignificantScoresButtonDown(app, event)
            FigureSignificantScores = figure;
            ExpandedAxesSignificantScores = copyobj(app.AxesSignificantScores, FigureSignificantScores);
            set(ExpandedAxesSignificantScores, 'Units', 'normalized',...
                'Position', get(FigureSignificantScores, 'DefaultAxesPosition'));
        end

        % Menu selected function: ExpandMenu
        function ExpandMenuSelected(app, event)
            % FigureSignificantScores = figure;
            % ExpandedAxesSignificantScores = copyobj(app.AxesSignificantScores, FigureSignificantScores);
            % set(ExpandedAxesSignificantScores, 'Units', 'normalized',...
            %     'Position', get(FigureSignificantScores, 'DefaultAxesPosition'));
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            ClusterSpectra(app, app.MainApp)
        end

        % Callback function
        function MaskMenuSelected(app, event)
            if strcmp(app.MaskMenu.Checked, 'on')
                app.MaskMenu.Checked = 'off';
            else
                app.MaskMenu.Checked = 'on';
            end
            
        end

        % Callback function
        function SpectraMenuSelected(app, event)
            if strcmp(app.SpectraMenu.Checked, 'on')
                app.SpectraMenu.Checked = 'off';
            else
                app.SpectraMenu.Checked = 'on';
            end    
            
        end

        % Callback function
        function SaveVarsMenuSelected(app, event)
%% OLD
% SelectedVars = {};            
% 
% 
% if strcmp(app.SpectraMenu.Checked,'on')
%     datamatrix = reshape(app.MainApp.Datacube, app.MainApp.Rows*app.MainApp.Columns, app.MainApp.Depth);
%     indexmatrix = reshape(1:app.MainApp.Rows*app.MainApp.Columns, app.MainApp.Rows, app.MainApp.Columns); % auxiliary variable with all indices of all pixels in matrix form
%     SignificantIndicesMatrix = app.AllSignificantPixelsMap.*indexmatrix;                                  % the non-significant pixels become 0 while the significant one retain their index
%     IndexVector = SignificantIndicesMatrix(SignificantIndicesMatrix>0);                                   % save the indeces in a vector
%     Spectra = datamatrix(IndexVector, :);
% 
%     SelectedVars{end+1} = 'Spectra' ; 
%     SelectedVars{end+1} = 'IndexVector' ; 
% end
% 
% 
% if strcmp(app.MaskMenu.Checked, 'on')
%     Mask = app.AllSignificantPixelsMap; % we need to save the variable in the workspace to save it
%     SelectedVars{end+1} = 'Mask';
% 
% end
% 
% % Selected variables 
% 
% 
% % name of the saved file
% dotPosition = strfind(app.MainApp.Filename, '.'); % where is the dot in the file name
% filename_no_extension = app.MainApp.Filename(1:dotPosition-1);
% filename = strcat(filename_no_extension, '_Significant_Spectra');            
% 
% uisave(SelectedVars, filename);
            %% NEW
            SaveData(app.MainApp, app, 0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.MaskMenu_2 = uimenu(app.LoadMenu);
            app.MaskMenu_2.Text = 'Mask';
            app.AxesSignificantScores = uiaxes(app.UIFigure);
            title(app.AxesSignificantScores, 'Significant Pixels in PC Space')
            xlabel(app.AxesSignificantScores, 'PCX')
            ylabel(app.AxesSignificantScores, 'PCY')
            zlabel(app.AxesSignificantScores, 'PCZ')
            app.AxesSignificantScores.ButtonDownFcn = createCallbackFcn(app, @AxesSignificantScoresButtonDown, true);
            app.AxesSignificantMap = uiaxes(app.UIFigure);
            title(app.AxesSignificantMap, 'Significant Pixels Map')
            zlabel(app.AxesSignificantMap, 'Z')
            app.AxesSignificantMap.ButtonDownFcn = createCallbackFcn(app, @AxesSignificantMapButtonDown, true);
            app.SaveMaskButton = uibutton(app.UIFigure, 'push');
            app.SaveMaskButton.Text = 'Save Mask';
            app.ApplyMaskButton = uibutton(app.UIFigure, 'push');
            app.ApplyMaskButton.Enable = 'off';
            app.ApplyMaskButton.Text = 'Apply Mask';
            app.CalculateMoransIButton = uibutton(app.UIFigure, 'push');
            app.CalculateMoransIButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMoransIButtonPushed, true);
            app.CalculateMoransIButton.Text = 'Calculate Moran''s I';
            app.AddPCButton = uibutton(app.UIFigure, 'push');
            app.AddPCButton.ButtonPushedFcn = createCallbackFcn(app, @AddPCButtonPushed, true);
            app.AddPCButton.Text = 'Add PC';
            app.PCZSpinner = uispinner(app.UIFigure);
            app.PCZSpinner.Limits = [0 Inf];
            app.PCXSpinnerLabel = uilabel(app.UIFigure);
            app.PCXSpinnerLabel.Text = 'PCX';
            app.PCYSpinnerLabel = uilabel(app.UIFigure);
            app.PCYSpinnerLabel.Text = 'PCY';
            app.PCYSpinner = uispinner(app.UIFigure);
            app.PCYSpinner.Limits = [0 Inf];
            app.PCXSpinner = uispinner(app.UIFigure);
            app.PCXSpinner.Limits = [0 Inf];
            app.PCspaceListBoxLabel = uilabel(app.UIFigure);
            app.PCspaceListBoxLabel.Text = 'PC space';
            app.PCspaceListBox = uilistbox(app.UIFigure);
            app.PCspaceListBox.Items = {''};
            app.PCspaceListBox.Value = '';
            app.PCSpinnerLabel = uilabel(app.UIFigure);
            app.PCSpinnerLabel.Text = 'PC';
            app.PCSpinner = uispinner(app.UIFigure);
            app.PCSpinner.Limits = [1 Inf];
            app.PCSpinner.ValueChangedFcn = createCallbackFcn(app, @PCSpinnerValueChanged, true);
            app.PCSpinner.Value = 1;
            app.PCZSpinnerLabel = uilabel(app.UIFigure);
            app.PCZSpinnerLabel.Text = 'PCZ';
            app.Panel = uipanel(app.UIFigure);
            app.SetthresholdforsignificantpixelsButton = uibutton(app.Panel, 'push');
            app.SetthresholdforsignificantpixelsButton.ButtonPushedFcn = createCallbackFcn(app, @SetthresholdforsignificantpixelsButtonPushed, true);
            app.SetthresholdforsignificantpixelsButton.Text = 'Set threshold for significant pixels';
            app.PCTabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.PCTabGroup);
            app.Tab.Title = 'Tab';
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.ClusterButton = uibutton(app.UIFigure, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'Cluster';
            app.FindSimilarButton = uibutton(app.UIFigure, 'push');
            app.FindSimilarButton.Enable = 'off';
            app.FindSimilarButton.Text = 'Find Similar';
            app.ofsignificantpixelsEditFieldLabel = uilabel(app.UIFigure);
            app.ofsignificantpixelsEditFieldLabel.Text = '# of significant pixels';
            app.ofsignificantpixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofsignificantpixelsEditField.Editable = 'off';
            app.ContextMenuPCListBox = uicontextmenu(app.UIFigure);
            app.Delete = uimenu(app.ContextMenuPCListBox);
            app.Delete.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.Delete.Text = 'Delete';
            % Assign app.ContextMenuPCListBox
            app.PCspaceListBox.ContextMenu = app.ContextMenuPCListBox;
            app.ContextMenuSignificantPixelsMap = uicontextmenu(app.UIFigure);
            app.ExpandMenu = uimenu(app.ContextMenuSignificantPixelsMap);
            app.ExpandMenu.MenuSelectedFcn = createCallbackFcn(app, @ExpandMenuSelected, true);
            app.ExpandMenu.Text = 'Expand';
            % Assign app.ContextMenuSignificantPixelsMap
            app.AxesSignificantMap.ContextMenu = app.ContextMenuSignificantPixelsMap;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpatialAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end