classdef ClusterSpectra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        NormalizeSwitch                 matlab.ui.control.Switch
        StDevsSwitch                    matlab.ui.control.Switch
        SelectmethodDropDown            matlab.ui.control.DropDown
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        SelectdistanceDropDown          matlab.ui.control.DropDown
        SelectdistanceDropDownLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        HierarchicalTab                 matlab.ui.container.Tab
        LinkageDropDown                 matlab.ui.control.DropDown
        LinkageDropDownLabel            matlab.ui.control.Label
        nOfClustersHierarchicalSpinner  matlab.ui.control.Spinner
        ofclustersSpinner_2Label        matlab.ui.control.Label
        DBSCANTab                       matlab.ui.container.Tab
        epsilonEditField                matlab.ui.control.NumericEditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        minptsSpinner                   matlab.ui.control.Spinner
        minptsSpinnerLabel              matlab.ui.control.Label
        KmeansTab                       matlab.ui.container.Tab
        nOfClustersKmeansSpinner        matlab.ui.control.Spinner
        ofclustersSpinnerLabel          matlab.ui.control.Label
        MaxIterSpinner                  matlab.ui.control.Spinner
        MaxIterSpinnerLabel             matlab.ui.control.Label
        SelectPCspaceListBox            matlab.ui.control.ListBox
        ClusterButton                   matlab.ui.control.Button
        SelectmethodDropDownLabel       matlab.ui.control.Label
        VisualizeButton                 matlab.ui.control.Button
        PCZSpinnerLabel                 matlab.ui.control.Label
        SelectPCspaceListBoxLabel       matlab.ui.control.Label
        PCXSpinner                      matlab.ui.control.Spinner
        PCYSpinner                      matlab.ui.control.Spinner
        PCYSpinnerLabel                 matlab.ui.control.Label
        PCXSpinnerLabel                 matlab.ui.control.Label
        PCZSpinner                      matlab.ui.control.Spinner
        AxesAverageSpectra              matlab.ui.control.UIAxes
        AxesClusteredMap                matlab.ui.control.UIAxes
        AxesClusteredPCSpace            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        
        Counter = 0;  % Counts the number of PCs and serves as index of the PCs
        CurrentPC % It's the number of the PC of the current tab
        PCSubspace = [] % Vector which contains the indices of the PCs used for the subspace
        PixelPC; % The scores of the pixels
        SpatialLag;

        SignificantPixelsMask = {}; % initialize as empty cell
        IndicesOfSignificantPixelsVector; % Vector with indices of significant pixels

        idx      
        
        cmap = 'parula' % initialization 
        Method % Saves the method used to cluster the data
        Parameters % Struct that saves the parameters used for the clustering, including method, distance
                   % MaxIter, etc...
                   % NB: Parameters are updated only after the Cluster!
                   % button is pressed!

        NClusters

        avgSpectra = {}; % vector with the vector of average spectra
        avgSpectraMax = {}; % vector with the maximum for each average spectrum
        stdevSpectra = {}; % vector with the stdev at each WL for the spectra
        
        avgSpectraHandles = {} % handles to the lines
        stdPatchHandles = {} % handles for the polygons representing the stdev of the spectra
        
        avgSpectraNormalizedHandles = {}; % handles to the normalized spectra
        stdPatchNormalizedHandles = {} % handles to the normalized patches
        %avgSpectrumHandle = {}; collects the handles of the lines representing the average
        
        Colors = [];
        MainApp 
        
 
    end
    
    properties (Access = private)
         
        Caller
    end

    %%% Clustering functions 
    methods (Access = private)
        
        function idx = clusterSpectraWithKMeans(app, data)
            app.NClusters = app.nOfClustersKmeansSpinner.Value;

            idx = kmeans(data, app.NClusters, ...
                'MaxIter',app.MaxIterSpinner.Value, ...
                'Distance', app.SelectdistanceDropDown.Value);

            % Delete the previously saved parameters
            app.Parameters = struct();
            % Update the parameters
            app.Parameters.Method = app.Method;
            app.Parameters.Distance = app.SelectdistanceDropDown.Value;
            app.Parameters.NClusters = app.NClusters;
            app.Parameters.MaxIter = app.MaxIterSpinner.Value;

            app.ClusterButton.Enable = 'on';
            
        end
       
        function  idx = clusterSpectraWithHierarchical(app, data)
            app.NClusters = app.nOfClustersHierarchicalSpinner.Value;

            tmp = linkage(data,lower(app.LinkageDropDown.Value), app.SelectdistanceDropDown.Value,'savememory','on');
            idx = cluster(tmp,'Maxclust',app.NClusters);

            app.ClusterButton.Enable = 'on';

            % Delete the previously saved parameters
            app.Parameters = struct();
            % Update the parameters
            app.Parameters.Method = app.Method;
            app.Parameters.Distance = app.SelectdistanceDropDown.Value;
            app.Parameters.NClusters = app.NClusters; % modify to accept dynamically the number of clusters
            app.Parameters.Linkage = app.LinkageDropDown.Value;


        end

        function  idx = clusterSpectraWithDBSCAN(app, data)            
            idx = dbscan(data,app.epsilonEditField.Value, app.minptsSpinner.Value, 'Distance', app.SelectdistanceDropDown.Value);

            % outliers are assigned index -1
            if sum(idx==-1)>0
                disp('Noise points have been detected and have been assigned index 1') % check if correct
                idx(idx==-1) = 0;
                idx = idx+1;
            end
            app.NClusters = max(idx);
            
            % Delete the previously saved parameters
            app.Parameters = struct();
            % Update the parameters
            app.Parameters.Method = app.Method;
            app.Parameters.Distance = app.SelectdistanceDropDown.Value;
            app.Parameters.Epsilon = app.epsilonEditField.Value;
            app.Parameters.MinPoints = app.minptsSpinner.Value;

            app.ClusterButton.Enable = 'on';

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, mainapp)
            app.MainApp = mainapp;
            app.Caller = caller;
            
            app.SelectPCspaceListBox.Items = {};
            for i = 1:app.MainApp.Depth
                newItemName = strcat('PC', num2str(i));
                app.SelectPCspaceListBox.Items(end+1) = {newItemName};
                app.SelectPCspaceListBox.ItemsData(end+1) = i;     
            end

            dist = retrieveDistances(app.SelectmethodDropDown.Value);
            app.SelectdistanceDropDown.Items = dist;

            app.Method = lower(app.SelectmethodDropDown.Value); % initialization
            app.Parameters = struct([]); % init

        end

        % Value changed function: SelectmethodDropDown
        function SelectmethodDropDownValueChanged(app, event)
            app.Method = lower(app.SelectmethodDropDown.Value);

            dist = retrieveDistances(app.Method);
            app.SelectdistanceDropDown.Items = {};
            app.SelectdistanceDropDown.Items = dist;

            % add all additional components for each method chosen
            % if strcmp(method, 'k-means')
            %     app.nOfClustersKmeansSpinner.Enable = 'on';
            % elseif strcmp(method, 'hierarchical')
            %     app.nOfClustersKmeansSpinner.Enable = 'on';
            % end

            % change active tab
            tabHandle = findobj(app.TabGroup, 'Title', app.SelectmethodDropDown.Value);
            app.TabGroup.SelectedTab = tabHandle;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;            
            app.SelectmethodDropDown.Value = selectedTab.Title;
            SelectmethodDropDownValueChanged(app, event);
        end

        % Value changed function: nOfClustersKmeansSpinner
        function nOfClustersKmeansSpinnerValueChanged(app, event)
            value = app.nOfClustersKmeansSpinner.Value;
            app.CurrentPC = value;
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
        % Set the spinners to appropriate values
        
        subspaceDimensions = length(app.SelectPCspaceListBox.Value);
        if subspaceDimensions < 1
            warndlg('Please select at least one PC.')
        else 
            sortedValues = sort(app.SelectPCspaceListBox.Value);

            % save the sorted PCs into a vector each time Cluster button is
            % pushed
            app.PCSubspace = sortedValues;

            sortedValues = [sortedValues 0 0];  % append two 0s in case only one PC was selected
                                                % useful for assigning correct value to the spinners
             app.PCXSpinner.Value = sortedValues(1);
             app.PCYSpinner.Value = sortedValues(2);
             app.PCZSpinner.Value = sortedValues(3);
        end
    
        % cluster data only if the selected subspace is of appropriate
        % dimensions
        if subspaceDimensions >= 1
            
            % first generate the dataset based on the significant pixels
            % and the selection of the PC space we want to be in (to be
            % selected in the drop down menu). 
            % indicesOfSignificantPixelsVector is the vector of indices of
            % the pixels that are significant after the spatial
            % autocorrelation analysis.
            indicesOfSignificantPixelsVector = reshape( ...
                reshape(1:app.MainApp.Rows*...
                app.MainApp.Columns, app.MainApp.Rows, app.MainApp.Columns).*...
                (app.Caller.AllSignificantPixelsMap>0), ...
                [],1);   

            indicesOfSignificantPixelsVector = ...
                indicesOfSignificantPixelsVector(indicesOfSignificantPixelsVector>0);

            % now data for which distances are calculated is going to be
            % the scores of the significant pixels in the indicated subspace
            data = app.MainApp.Scores(indicesOfSignificantPixelsVector, app.SelectPCspaceListBox.Value);

           
            switch lower(app.TabGroup.SelectedTab.Title) % maybe the title of the active tab
                case 'hierarchical'
                    %run hierarchical clustering and update the parameters
                    app.idx = clusterSpectraWithHierarchical(app, data);
                case 'dbscan'
                    % run DBSCAN and update the parameters
                    app.idx = clusterSpectraWithDBSCAN(app, data);    
                case 'k-means'
                    % run k-means and update the parameters
                    app.idx = clusterSpectraWithKMeans(app, data);
                case 'clusterdata'
                    % run clusterdata and update the parameters
                    
            end

        end

        VisualizeButtonPushed(app, event);


        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            % collect the spinner values in a vector
            spinnerValues = [app.PCXSpinner.Value app.PCYSpinner.Value app.PCZSpinner.Value];

            % reorder in such a way that the zeros stay at the end            
            spinnerValues = [spinnerValues(spinnerValues~=0), spinnerValues(spinnerValues==0)];
            
            % assign the reordered values to the spinners
            for i = 1:3
              switch i
                case 1
                   app.PCXSpinner.Value = spinnerValues(i);
                case 2
                   app.PCYSpinner.Value = spinnerValues(i);
                case 3
                   app.PCZSpinner.Value = spinnerValues(i);
              end
            end

            xlabel(app.AxesClusteredPCSpace, strcat('PC', num2str(app.PCXSpinner.Value)));
            ylabel(app.AxesClusteredPCSpace, strcat('PC', num2str(app.PCYSpinner.Value)));
            zlabel(app.AxesClusteredPCSpace, strcat('PC', num2str(app.PCZSpinner.Value)));


            % first generate the dataset based on the significant pixels
            % and the selection of the PC space we want to be in (to be
            % selected in the drop down menu)
            indicesOfSignificantPixelsVector = reshape( ...
                reshape(1:app.MainApp.Rows*...
                app.MainApp.Columns, app.MainApp.Rows, app.MainApp.Columns).*...
                (app.Caller.AllSignificantPixelsMap>0), ...
                [],1);
            indicesOfSignificantPixelsVector = ...
                indicesOfSignificantPixelsVector(indicesOfSignificantPixelsVector>0);
            data = app.MainApp.Scores(indicesOfSignificantPixelsVector, :);
            app.IndicesOfSignificantPixelsVector = indicesOfSignificantPixelsVector;

           
            % generate the vector of colors
            app.Colors = eval([app.cmap, '(',  num2str(app.NClusters), ')']);


            % plot a 2D or 3D scatterplot with indexed points
            if length(spinnerValues(spinnerValues~=0)) == 1
                cla(app.AxesClusteredPCSpace)
                view(app.AxesClusteredPCSpace,2)

                % set also PCY to the same value and plot the 2D plot
                app.PCYSpinner.Value = app.PCXSpinner.Value;            
                hold(app.AxesClusteredPCSpace, "on")
                for i = 1:app.NClusters                    
                    scatter(app.AxesClusteredPCSpace, ...
                        data(app.idx == i, app.PCXSpinner.Value), ...
                        data(app.idx == i, app.PCYSpinner.Value),...                        
                        3, app.Colors(i,:), 'filled' )
                end
                grid(app.AxesClusteredPCSpace, "on")
                hold(app.AxesClusteredPCSpace, "off")

            elseif length(spinnerValues(spinnerValues~=0)) == 2
                cla(app.AxesClusteredPCSpace)
                view(app.AxesClusteredPCSpace,2)    

                hold(app.AxesClusteredPCSpace, "on")
                for i = 1:app.NClusters                    
                    scatter(app.AxesClusteredPCSpace, ...
                        data(app.idx == i, app.PCXSpinner.Value), ...
                        data(app.idx == i, app.PCYSpinner.Value),...                        
                        3, app.Colors(i,:), 'filled' )
                end
                grid(app.AxesClusteredPCSpace, "on")
                hold(app.AxesClusteredPCSpace, "off")

            elseif length(spinnerValues(spinnerValues~=0)) == 3
                cla(app.AxesClusteredPCSpace)
                view(app.AxesClusteredPCSpace,3)               
                hold(app.AxesClusteredPCSpace, "on")
                for i = 1:app.NClusters                    
                    scatter3(app.AxesClusteredPCSpace, ...
                        data(app.idx == i, app.PCXSpinner.Value), ...
                        data(app.idx == i, app.PCYSpinner.Value),...
                        data(app.idx == i, app.PCZSpinner.Value),...
                        3, app.Colors(i,:), 'filled' )
                end
                
                %lgd = legend(app.AxesClusteredPCSpace, arrayfun(@(i) sprintf('%d', i), 1:app.NClusters, 'UniformOutput', false), 'Location', 'bestoutside');                
                hold(app.AxesClusteredPCSpace, "off")
                grid(app.AxesClusteredPCSpace, "on")
            end
            
            AxesClusteredPCSpaceButtonDown(app, event)    

            clusteredMap = zeros(app.MainApp.Rows,app.MainApp.Columns);
            % clusteredMap is the map of the clustered indices: 0 where the
            % pixel is not significant and the cluster index assigned with
            % clustering where it is.
            clusteredMap(indicesOfSignificantPixelsVector(indicesOfSignificantPixelsVector>0)) = app.idx;

            clusteredMapR = mat2gray(app.MainApp.Intensity);
            clusteredMapG = clusteredMapR;
            clusteredMapB = clusteredMapR;

            for i = 1:app.NClusters
                clusteredMapR(clusteredMap == i) = app.Colors(i, 1);
                clusteredMapG(clusteredMap == i) = app.Colors(i, 2);
                clusteredMapB(clusteredMap == i) = app.Colors(i, 3);
            end
            clusteredMapRGB = cat(3, clusteredMapR,clusteredMapG,clusteredMapB);
            imagesc(app.AxesClusteredMap, clusteredMapRGB);
            app.AxesClusteredMap.XLim = [0 app.MainApp.Columns];
            app.AxesClusteredMap.YLim = [0 app.MainApp.Rows];
            daspect(app.AxesClusteredMap, [app.MainApp.Rows app.MainApp.Columns 1])

            
          % Draw the average spectra of clusters
          % we find the indices of the pixels which belong to cluster i

          datamatrix = reshape(app.MainApp.Datacube, app.MainApp.Rows*app.MainApp.Columns, app.MainApp.Depth);
         
          % reset the average spectra of each cluster
          cla(app.AxesAverageSpectra)
          app.avgSpectra = {};
          app.avgSpectraMax = {};% initialization of cell
          app.stdPatchHandles = {};
          app.stdevSpectra = {};
   
          % Calculate the q.ties and save them in appropriate cells
          for i = 1:app.NClusters 
              tmpidx = indicesOfSignificantPixelsVector(app.idx == i);
              app.avgSpectra{i}= mean(datamatrix(tmpidx,:));              
              app.avgSpectraMax{i} = max(app.avgSpectra{i});
              app.stdevSpectra{i} = std(datamatrix(tmpidx,:));              
          end

          % plot the average spectra with stdevs         
          hold(app.AxesAverageSpectra, "on")
          for i = 1:app.NClusters           
            xi = [app.MainApp.Calibration, flip(app.MainApp.Calibration)];
            yi = [app.stdevSpectra{i}+app.avgSpectra{i}, flip(app.avgSpectra{i}-app.stdevSpectra{i})];                
            app.stdPatchHandles{i} = patch( app.AxesAverageSpectra, xi, yi, app.Colors(i,:), 'EdgeAlpha', 0, 'FaceAlpha', 0.3 );
            app.stdPatchNormalizedHandles{i} = patch( app.AxesAverageSpectra, xi, yi/app.avgSpectraMax{i},...
                app.Colors(i,:), 'EdgeAlpha', 0, 'FaceAlpha', 0.3, 'Visible', 'off');
          end
          hold(app.AxesAverageSpectra, "off")

          % Then the average spectra on top of the patches
          hold(app.AxesAverageSpectra, "on")
          for i = 1:app.NClusters          
            app.avgSpectraHandles{i} = plot(app.AxesAverageSpectra, app.MainApp.Calibration, app.avgSpectra{i}, ...
                'Color', app.Colors(i,:));  
            app.avgSpectraNormalizedHandles{i} = plot(app.AxesAverageSpectra, app.MainApp.Calibration, app.avgSpectra{i}/app.avgSpectraMax{i}, ...
                'Color', app.Colors(i,:), 'Visible','off');
          end
          hold(app.AxesAverageSpectra, "off")

          app.AxesAverageSpectra.XLim = [app.MainApp.Calibration(1) app.MainApp.Calibration(end)];
          grid(app.AxesAverageSpectra, 'on');

          %StDevsSwitchValueChanged(app, event)
          NormalizeSwitchValueChanged(app, event)           
                      
        end

        % Button down function: AxesClusteredMap
        function AxesClusteredMapButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureSignificantMap = figure;
            ExpandedAxesSignificantMap = copyobj(app.AxesClusteredMap, FigureSignificantMap);
            set(ExpandedAxesSignificantMap, 'Units', 'normalized',...
                'Position', get(FigureSignificantMap, 'DefaultAxesPosition'));

        end

        % Button down function: AxesClusteredPCSpace
        function AxesClusteredPCSpaceButtonDown(app, event)
            FigureSignificantScores = figure;
            ExpandedAxesSignificantScores = copyobj(app.AxesClusteredPCSpace, FigureSignificantScores);
            set(ExpandedAxesSignificantScores, 'Units', 'normalized',...
                'Position', get(FigureSignificantScores, 'DefaultAxesPosition'));
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.cmap = app.ColormapDropDown.Value;
           
        end

        % Button down function: AxesAverageSpectra
        function AxesAverageSpectraButtonDown(app, event)
             % Create new figure with a copy of the axes
            FigureAverageSpectra = figure;
            ExpandedAxesAxesAverageSpectra = copyobj(app.AxesAverageSpectra, FigureAverageSpectra);
            set(ExpandedAxesAxesAverageSpectra, 'Units', 'normalized',...
                'Position', get(FigureAverageSpectra, 'DefaultAxesPosition'));

        end

        % Value changed function: StDevsSwitch
        function StDevsSwitchValueChanged(app, event)
            value = app.StDevsSwitch.Value;

            if isempty(value) % the swicth is OFF
                for i = 1:app.NClusters
                    set(app.stdPatchHandles{i}, 'Visible', 'off');                    
                    set(app.stdPatchNormalizedHandles{i}, 'Visible', 'off');
                end
            elseif isempty(app.NormalizeSwitch.Value) % the StDevSwitch is ON but the NormalizeSwitch is OFF
                for i = 1:app.NClusters
                    set(app.stdPatchHandles{i}, 'Visible', 'on');
                    set(app.stdPatchNormalizedHandles{i}, 'Visible', 'off');
                end
            elseif not(isempty(app.NormalizeSwitch.Value)) % the StDevSwitch is ON and the NormalizeSwitch is ON
                for i = 1:app.NClusters
                    set(app.stdPatchNormalizedHandles{i}, 'Visible', 'on');
                    set(app.stdPatchHandles{i}, 'Visible', 'off');
                end
            end 


        end

        % Value changed function: NormalizeSwitch
        function NormalizeSwitchValueChanged(app, event)
            value = app.NormalizeSwitch.Value;

            if isempty(value) % The switch is OFF, we want actual average spectra
                for i = 1:app.NClusters
                   set(app.avgSpectraHandles{i}, 'Visible', 'on')
                   set(app.avgSpectraNormalizedHandles{i}, 'Visible', 'off')
                end
            else % The switch is ON, we want normalized average spectra
                for i = 1:app.NClusters
                   set(app.avgSpectraHandles{i}, 'Visible', 'off')
                   set(app.avgSpectraNormalizedHandles{i}, 'Visible', 'on')
                end
            end

            StDevsSwitchValueChanged(app, event)

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isvalid(app.Caller) 
                SaveData(app.MainApp, app.Caller, app)
            else
                SaveData(app.MainApp, 0, app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.AxesClusteredPCSpace = uiaxes(app.UIFigure);
            title(app.AxesClusteredPCSpace, 'Clustered Pixels in PC Space')
            xlabel(app.AxesClusteredPCSpace, 'PCX')
            ylabel(app.AxesClusteredPCSpace, 'PCY')
            zlabel(app.AxesClusteredPCSpace, 'PCZ')
            app.AxesClusteredPCSpace.ButtonDownFcn = createCallbackFcn(app, @AxesClusteredPCSpaceButtonDown, true);
            app.AxesClusteredMap = uiaxes(app.UIFigure);
            title(app.AxesClusteredMap, 'Clustered Map')
            zlabel(app.AxesClusteredMap, 'Z')
            app.AxesClusteredMap.ButtonDownFcn = createCallbackFcn(app, @AxesClusteredMapButtonDown, true);
            app.AxesAverageSpectra = uiaxes(app.UIFigure);
            title(app.AxesAverageSpectra, 'Average Spectra')
            zlabel(app.AxesAverageSpectra, 'Z')
            app.AxesAverageSpectra.ButtonDownFcn = createCallbackFcn(app, @AxesAverageSpectraButtonDown, true);
            app.PCZSpinner = uispinner(app.UIFigure);
            app.PCXSpinnerLabel = uilabel(app.UIFigure);
            app.PCXSpinnerLabel.Text = 'PCX';
            app.PCYSpinnerLabel = uilabel(app.UIFigure);
            app.PCYSpinnerLabel.Text = 'PCY';
            app.PCYSpinner = uispinner(app.UIFigure);
            app.PCXSpinner = uispinner(app.UIFigure);
            app.SelectPCspaceListBoxLabel = uilabel(app.UIFigure);
            app.SelectPCspaceListBoxLabel.Text = 'Select PC space';
            app.PCZSpinnerLabel = uilabel(app.UIFigure);
            app.PCZSpinnerLabel.Text = 'PCZ';
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.SelectmethodDropDownLabel = uilabel(app.UIFigure);
            app.SelectmethodDropDownLabel.Text = 'Select method';
            app.ClusterButton = uibutton(app.UIFigure, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'Cluster!';
            app.SelectPCspaceListBox = uilistbox(app.UIFigure);
            app.SelectPCspaceListBox.Items = {''};
            app.SelectPCspaceListBox.Multiselect = 'on';
            app.SelectPCspaceListBox.Value = {''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HierarchicalTab = uitab(app.TabGroup);
            app.HierarchicalTab.Title = 'Hierarchical';
            app.ofclustersSpinner_2Label = uilabel(app.HierarchicalTab);
            app.ofclustersSpinner_2Label.Text = '# of clusters';
            app.nOfClustersHierarchicalSpinner = uispinner(app.HierarchicalTab);
            app.nOfClustersHierarchicalSpinner.Limits = [1 Inf];
            app.nOfClustersHierarchicalSpinner.Value = 1;
            app.LinkageDropDownLabel = uilabel(app.HierarchicalTab);
            app.LinkageDropDownLabel.Text = 'Linkage';
            app.LinkageDropDown = uidropdown(app.HierarchicalTab);
            app.LinkageDropDown.Items = {'Ward', 'Single', 'Complete', 'Average', 'Centroid', 'Median'};
            app.LinkageDropDown.Value = 'Ward';
            app.DBSCANTab = uitab(app.TabGroup);
            app.DBSCANTab.Title = 'DBSCAN';
            app.minptsSpinnerLabel = uilabel(app.DBSCANTab);
            app.minptsSpinnerLabel.Text = 'minpts';
            app.minptsSpinner = uispinner(app.DBSCANTab);
            app.epsilonEditFieldLabel = uilabel(app.DBSCANTab);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.DBSCANTab, 'numeric');
            app.KmeansTab = uitab(app.TabGroup);
            app.KmeansTab.Title = 'K-means';
            app.MaxIterSpinnerLabel = uilabel(app.KmeansTab);
            app.MaxIterSpinnerLabel.Text = 'MaxIter';
            app.MaxIterSpinner = uispinner(app.KmeansTab);
            app.MaxIterSpinner.Limits = [1 Inf];
            app.MaxIterSpinner.Value = 100;
            app.ofclustersSpinnerLabel = uilabel(app.KmeansTab);
            app.ofclustersSpinnerLabel.Text = '# of clusters';
            app.nOfClustersKmeansSpinner = uispinner(app.KmeansTab);
            app.nOfClustersKmeansSpinner.Limits = [1 Inf];
            app.nOfClustersKmeansSpinner.ValueChangedFcn = createCallbackFcn(app, @nOfClustersKmeansSpinnerValueChanged, true);
            app.nOfClustersKmeansSpinner.Value = 1;
            app.SelectdistanceDropDownLabel = uilabel(app.UIFigure);
            app.SelectdistanceDropDownLabel.Text = 'Select distance';
            app.SelectdistanceDropDown = uidropdown(app.UIFigure);
            app.SelectdistanceDropDown.Items = {};
            app.SelectdistanceDropDown.Value = {};
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'sky', 'abyss', 'jet', 'lines', 'colorcube', 'prism', 'flag'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'parula';
            app.SelectmethodDropDown = uidropdown(app.UIFigure);
            app.SelectmethodDropDown.Items = {'Hierarchical', 'DBSCAN', 'K-means', 'clusterdata'};
            app.SelectmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectmethodDropDownValueChanged, true);
            app.SelectmethodDropDown.Value = 'Hierarchical';
            app.StDevsSwitch = uiswitch(app.UIFigure, 'slider');
            app.StDevsSwitch.Items = {'', 'StDevs'};
            app.StDevsSwitch.ValueChangedFcn = createCallbackFcn(app, @StDevsSwitchValueChanged, true);
            app.StDevsSwitch.Value = '';
            app.NormalizeSwitch = uiswitch(app.UIFigure, 'slider');
            app.NormalizeSwitch.Items = {'', 'Normalize'};
            app.NormalizeSwitch.ValueChangedFcn = createCallbackFcn(app, @NormalizeSwitchValueChanged, true);
            app.NormalizeSwitch.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClusterSpectra(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end