classdef SaveData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveButton                      matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        MAOriginalDatacubeCheckBox      matlab.ui.control.CheckBox
        FilePathEditField               matlab.ui.control.EditField
        FilePathEditFieldLabel          matlab.ui.control.Label
        CSAverageSectraCheckBox         matlab.ui.control.CheckBox
        CSClustersCheckBox              matlab.ui.control.CheckBox
        CSPCspaceCheckBox               matlab.ui.control.CheckBox
        CSClusteringParametersCheckBox  matlab.ui.control.CheckBox
        SAI_localthresholdsCheckBox     matlab.ui.control.CheckBox
        SAListofPCsusedCheckBox         matlab.ui.control.CheckBox
        SAofsignificantpixelsCheckBox   matlab.ui.control.CheckBox
        SASignificantPixelsMapCheckBox  matlab.ui.control.CheckBox
        MAExplainedVarianceCheckBox     matlab.ui.control.CheckBox
        MALoadingsCheckBox              matlab.ui.control.CheckBox
        MADatacubePCscoresCheckBox      matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        MainApp
        SpatialAnalysisCaller
        ClusterSpectraCaller

        Filename
        Location
        FullFilePath

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, spatialAnalysisCaller, clusterSpectraCaller)
            app.MainApp = mainapp; % save Main App handle
            app.SpatialAnalysisCaller = spatialAnalysisCaller;
            app.ClusterSpectraCaller = clusterSpectraCaller;

            % The Caller inputs are either handles to active apps or the
            % number 0, if the window is not active. In that case, the
            % variables generated by that app are greyed out. 

            if spatialAnalysisCaller == 0
                % set all variables relative to that page to uninteractable
                app.SASignificantPixelsMapCheckBox.Value = 0;
                app.SASignificantPixelsMapCheckBox.Enable = 'Off';
                app.SAofsignificantpixelsCheckBox.Value = 0;
                app.SAofsignificantpixelsCheckBox.Enable = 'Off';
                app.SAListofPCsusedCheckBox.Value = 0;
                app.SAListofPCsusedCheckBox.Enable = 'Off';
                app.SAI_localthresholdsCheckBox.Value = 0;
                app.SAI_localthresholdsCheckBox.Enable = 'Off';
            end

            if clusterSpectraCaller == 0
                % set all variables relative to that page to uninteractable
                app.CSAverageSectraCheckBox.Value = 0;
                app.CSAverageSectraCheckBox.Enable = 'Off';
                app.CSClustersCheckBox.Value = 0;
                app.CSClustersCheckBox.Enable = 'Off';
                app.CSPCspaceCheckBox.Value = 0;
                app.CSPCspaceCheckBox.Enable = 'Off';
                app.CSClusteringParametersCheckBox.Value = 0;
                app.CSClusteringParametersCheckBox.Enable = 'Off';
            end

       
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            defaultFolder = app.MainApp.Filepath;
            [filename, location] = uiputfile(fullfile(defaultFolder, '*.mat'), 'Save File' );
            app.Location = location;
            app.Filename = filename;
            app.FullFilePath = fullfile(location, filename);
            app.FilePathEditField.Value = app.FullFilePath;

            % Refocus on the savedata app
            figure(app.MainApp.UIFigure)
            figure(app.UIFigure)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = app.Filename; 
            
            % We could add the file path directly  
            % app.FullFilePath = app.FilePathEditField.Value;
            % 
            % if isempty(filename)
            %     filename = 'default_file_name.mat';
            % end

            save(app.FullFilePath, 'filename') % create empty file in which to append the variables
            
            MAVarNames = {}; % Names of Variables from Main App (PCA_imaging.mlapp)

            if app.MAOriginalDatacubeCheckBox.Value == 1                
                Datacube = app.MainApp.Datacube;
                MAVarNames{end+1} = 'Datacube';
            end

            if app.MADatacubePCscoresCheckBox.Value == 1                
                PCScores = app.MainApp.Scores;
                MAVarNames{end+1} = 'PCScores';
            end

            if app.MALoadingsCheckBox.Value == 1
                Loadings = app.MainApp.V; 
                MAVarNames{end+1} = 'Loadings';
            end 

            if app.MAExplainedVarianceCheckBox.Value == 1
                ExplainedVar = app.MainApp.Explained; 
                MAVarNames{end+1} = 'ExplainedVar';
            end 

            if not(isempty(MAVarNames))
                save(app.FullFilePath, '-append', MAVarNames{:})
            end

            if not(isempty(MAVarNames))
                for i = 1:length(MAVarNames)
                    clear(MAVarNames{i})
                end
            end
            clear MAVarNames
            

            %%
            SAVarNames = {}; % Names of Variables from SpatialAnalysis.mlapp

            if app.SASignificantPixelsMapCheckBox.Value == 1                
                SignificantPixelsMap = app.SpatialAnalysisCaller.AllSignificantPixelsMap;
                SAVarNames{end+1} = 'SignificantPixelsMap';
            end

            if app.SAofsignificantpixelsCheckBox.Value == 1                
                %NumOfSignificantPixels = app.SpatialAnalysisCaller.NumberOfSignificantPixels;
                NumOfSignificantPixels = sum(sum(SignificantPixelsMap));
                SAVarNames{end+1} = 'NumOfSignificantPixels';
            end

            if app.SAListofPCsusedCheckBox.Value == 1                
                ListOfPCUsed = app.SpatialAnalysisCaller.PCspaceListBox.Items;
                SAVarNames{end+1} = 'ListOfPCUsed';
            end

            % I am missing the I_local_thresholds
            if not(isempty(SAVarNames))
                save(app.FullFilePath, '-append', SAVarNames{:})
                for i = 1:length(SAVarNames)
                    clear(SAVarNames{i})
                end
            end

            clear SAVarNames

            %%
            CSVarNames = {}; % Names of Variables from ClusterSpectra.mlapp

            if app.CSClusteringParametersCheckBox.Value == 1   
                % switch app.ClusterSpectraCaller.Parameters.Method
                %     case 'hierarchical'   
                %         % HierarchicalLinkage = app.ClusterSpectraCaller.LinkageDropDown.Value;
                %         % HierarchicalDistance = app.ClusterSpectraCaller.SelectdistanceDropDown.Value;
                %         % HierarchicalNClusters = app.ClusterSpectraCaller.nOfClustersHierarchicalSpinner.Value;
                %         % CSVarNames{end+1} = 'KMeansNIter';
                %         % CSVarNames{end+1} = 'KMeansDistance';
                %         % CSVarNames{end+1} = 'KMeansNClusters';
                %         Parameters = app.ClusterSpectraCaller.Parameters;
                %         CSVarNames{end+1} = 'Parameters';
                %     case 'dbscan'
                % 
                %     case 'k-means'
                %         KMeansNIter = app.ClusterSpectraCaller.MaxIterSpinner.Value;
                %         KMeansDistance = app.ClusterSpectraCaller.SelectdistanceDropDown.Value;
                %         KMeansNClusters = app.ClusterSpectraCaller.NClusters;
                %         CSVarNames{end+1} = 'KMeansNIter';
                %         CSVarNames{end+1} = 'KMeansDistance';
                %         CSVarNames{end+1} = 'KMeansNClusters';
                %     case 'clusterdata'
                % 
                % end
                ClusteringParameters = app.ClusterSpectraCaller.Parameters;
                CSVarNames{end+1} = 'ClusteringParameters';
            end

            if app.CSPCspaceCheckBox.Value == 1                
                PCSpace = app.ClusterSpectraCaller.PCSubspace;
                CSVarNames{end+1} = 'PCSpace';
            end

            if app.CSClustersCheckBox.Value == 1   
                % - PixelIndices is a vector that goes from 1 to the total
                % number of pixels in the picture
                % - PixelCluster is a vector that contains the index of the
                % cluster the pixel in the corresponding position has been
                % assigned to and is Nan if the pixel wasn's significant
                
               
                PixelIndices = app.ClusterSpectraCaller.IndicesOfSignificantPixelsVector;
                PixelClusters = app.ClusterSpectraCaller.idx;                
                ClusteredMap = zeros(app.MainApp.Rows, app.MainApp.Columns);
                ClusteredMap(PixelIndices) = PixelClusters;
                CSVarNames{end+1} = 'PixelIndices';
                CSVarNames{end+1} = 'PixelClusters';
                CSVarNames{end+1} = 'ClusteredMap';
            end

            if app.CSAverageSectraCheckBox.Value == 1                
                AverageSpectra = app.ClusterSpectraCaller.avgSpectra;
                StdDevSpectra = app.ClusterSpectraCaller.stdevSpectra;
                CSVarNames{end+1} = 'AverageSpectra';
                CSVarNames{end+1} = 'StdDevSpectra';
            end
            
            if not(isempty(CSVarNames))
                save(app.FullFilePath, '-append', CSVarNames{:})
                for i = 1:length(CSVarNames)
                    clear(CSVarNames{i})
                end
            end
            clear SAVarNames


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MADatacubePCscoresCheckBox = uicheckbox(app.UIFigure);
            app.MADatacubePCscoresCheckBox.Text = 'Datacube PC scores';
            app.MADatacubePCscoresCheckBox.Value = true;
            app.MALoadingsCheckBox = uicheckbox(app.UIFigure);
            app.MALoadingsCheckBox.Text = 'Loadings';
            app.MALoadingsCheckBox.Value = true;
            app.MAExplainedVarianceCheckBox = uicheckbox(app.UIFigure);
            app.MAExplainedVarianceCheckBox.Text = 'Explained Variance';
            app.MAExplainedVarianceCheckBox.Value = true;
            app.SASignificantPixelsMapCheckBox = uicheckbox(app.UIFigure);
            app.SASignificantPixelsMapCheckBox.Text = 'Significant Pixels Map';
            app.SASignificantPixelsMapCheckBox.Value = true;
            app.SAofsignificantpixelsCheckBox = uicheckbox(app.UIFigure);
            app.SAofsignificantpixelsCheckBox.Text = '# of significant pixels';
            app.SAofsignificantpixelsCheckBox.Value = true;
            app.SAListofPCsusedCheckBox = uicheckbox(app.UIFigure);
            app.SAListofPCsusedCheckBox.Text = 'List of PCs used';
            app.SAListofPCsusedCheckBox.Value = true;
            app.SAI_localthresholdsCheckBox = uicheckbox(app.UIFigure);
            app.SAI_localthresholdsCheckBox.Enable = 'off';
            app.SAI_localthresholdsCheckBox.Text = 'I_local thresholds';
            app.CSClusteringParametersCheckBox = uicheckbox(app.UIFigure);
            app.CSClusteringParametersCheckBox.Text = 'Clustering Parameters';
            app.CSClusteringParametersCheckBox.Value = true;
            app.CSPCspaceCheckBox = uicheckbox(app.UIFigure);
            app.CSPCspaceCheckBox.Text = 'PC space';
            app.CSPCspaceCheckBox.Value = true;
            app.CSClustersCheckBox = uicheckbox(app.UIFigure);
            app.CSClustersCheckBox.Text = 'Clusters';
            app.CSClustersCheckBox.Value = true;
            app.CSAverageSectraCheckBox = uicheckbox(app.UIFigure);
            app.CSAverageSectraCheckBox.Text = 'Average Sectra';
            app.CSAverageSectraCheckBox.Value = true;
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.MAOriginalDatacubeCheckBox = uicheckbox(app.UIFigure);
            app.MAOriginalDatacubeCheckBox.Text = 'Original Datacube';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end