classdef SVD_Spatial_signal_ratio_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        Interferogram_crop         matlab.ui.container.ButtonGroup
        text26                     matlab.ui.control.Label
        text25                     matlab.ui.control.Label
        t_maxValue                 matlab.ui.control.EditField
        t_minValue                 matlab.ui.control.EditField
        min_t_value                matlab.ui.control.Label
        max_t_value                matlab.ui.control.Label
        text24                     matlab.ui.control.Label
        text23                     matlab.ui.control.Label
        XLimMaxValue               matlab.ui.control.EditField
        XLimMinValue               matlab.ui.control.EditField
        N_SVs_Edit                 matlab.ui.control.EditField
        text21                     matlab.ui.control.Label
        FixYInterferogram          matlab.ui.control.CheckBox
        InterferogramInspection    matlab.ui.control.Button
        NpixelsMeanValue           matlab.ui.control.EditField
        text20                     matlab.ui.control.Label
        text19                     matlab.ui.control.Label
        text18                     matlab.ui.control.Label
        ExitButton                 matlab.ui.control.Button
        FixYSpectrum               matlab.ui.control.CheckBox
        SpectrumInspection         matlab.ui.control.Button
        text11                     matlab.ui.control.Label
        text10                     matlab.ui.control.Label
        y_selectedValue            matlab.ui.control.EditField
        text9                      matlab.ui.control.Label
        x_selectedValue            matlab.ui.control.EditField
        text7                      matlab.ui.control.Label
        text6                      matlab.ui.control.Label
        text5                      matlab.ui.control.Label
        text4                      matlab.ui.control.Label
        thresholdValue             matlab.ui.control.EditField
        text3                      matlab.ui.control.Label
        uibuttongroup1             matlab.ui.container.ButtonGroup
        text14                     matlab.ui.control.Label
        text13                     matlab.ui.control.Label
        YLimMaxValue               matlab.ui.control.EditField
        YLimMinValue               matlab.ui.control.EditField
        uibuttongroup2             matlab.ui.container.ButtonGroup
        text17                     matlab.ui.control.Label
        GammaValue                 matlab.ui.control.EditField
        text16                     matlab.ui.control.Label
        BlackValue                 matlab.ui.control.EditField
        text15                     matlab.ui.control.Label
        SaturationValue            matlab.ui.control.EditField
        text22                     matlab.ui.control.Label
        text2                      matlab.ui.control.Label
        uibuttongroup3             matlab.ui.container.ButtonGroup
        Plot_Interf                matlab.ui.control.UIAxes
        Plot_spectr                matlab.ui.control.UIAxes
        Immagine                   matlab.ui.control.UIAxes
        Plot_Spatial_signal_ratio  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SVD_Spatial_signal_ratio_OpeningFcn(app, varargin)
            % --- Executes just before SVD_Spatial_signal_ratio is made visible.

            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SVD_Spatial_signal_ratio (see VARARGIN)

            % Choose default command line output for SVD_Spatial_signal_ratio
            handles.output = hObject;

            global U V S a b p_variables spatial_signal_ratio t f threshold x_selected y_selected ...
                apod yLimMin yLimMax xLimMin xLimMax black ...
                saturation gamma max_image immagine spectrum ...
                HyperMatrix_new spectrum_y_fixed spectrum_y_lim interferogram interf_y_lim ...
                interf_y_fixed pixels_mean n_SVs t_min t_max dir

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes SVD_Spatial_signal_ratio wait for user response (see UIRESUME)
            % uiwait(handles.figure1);

            U=varargin{2}; %U matrix of SVD of HyperMatrix (temporal hypercube)
            S=varargin{3}; %S matrix of SVD of HyperMatrix (temporal hypercube)
            V=varargin{4}; %V matrix of SVD of HyperMatrix (temporal hypercube)
            a=varargin{5}; %this is size(HyperMatrix,1); y dimension
            b=varargin{6}; %this is size(HyperMatrix,2); x dimension
            p_variables=varargin{7}; %this is size(HyperMatrix,3); delay dimension
            spatial_signal_ratio=varargin{8}; %spatial signal ratio vector
            threshold=varargin{9}; %first threshold for Spatial Signal Ratio to be considered
            n_SVs=varargin{10}; %number of Singular Values (SVs) considered
            t=varargin{11}; %delay (or motor position axis)
            f=varargin{12}; %frequency (or pseudofrequency axis)
            dir=varargin{13} %directory for the original hypercube

            if threshold>max(spatial_signal_ratio)
                threshold=max(spatial_signal_ratio);
            end

            if threshold<min(spatial_signal_ratio)
                threshold=min(spatial_signal_ratio);
            end

            %range for y axis in Spatial Signal Ratio Plot
            yLimMin=min(spatial_signal_ratio).*0.9;
            yLimMax=max(spatial_signal_ratio).*1.1;

            %range for x axis in Spatial Signal Ratio Plot
            xLimMin=0;
            xLimMax=n_SVs;

            set(handles.thresholdValue,'String',num2str(threshold));
            set(handles.YLimMinValue,'String',num2str(yLimMin));
            set(handles.YLimMaxValue,'String',num2str(yLimMax));
            set(handles.N_SVs_Edit,'String',num2str(n_SVs));

            plot(handles.Plot_Spatial_signal_ratio,spatial_signal_ratio(1:n_SVs),'b','linewidth',2); axis tight;
            set(handles.Plot_Spatial_signal_ratio,'YLim',[yLimMin yLimMax]);
            set(handles.Plot_Spatial_signal_ratio,'XLim',[xLimMin xLimMax]);

            hold on;
            yline(handles.Plot_Spatial_signal_ratio,threshold,'r','linewidth',1);
            hold off; %this is important to manage two different graphs in the GUI

            SVs=diag(S); %singular values (SVs)

            SVs_swap=zeros(size(SVs));
            SVs_swap(1:end-(length(SVs)-n_SVs))=SVs(1:n_SVs); %keep only the first n_SVs SVs

            SVs=SVs_swap;
            clear SVs_swap

            SVs(spatial_signal_ratio<threshold)=0; %put to 0 all the SVs associated to low spatial_signal_ratio

            S_new=diag(SVs); %recalculate the S matrix

            X_new=(U*S_new*V')'; %recalculate the denoised dataset

            HyperMatrix_new=reshape(X_new,a,b,p_variables); %reshape properly the dataset

            Sample=sum(squeeze(abs(HyperMatrix_new)),3);
            immagine=zeros(size(Sample,1),size(Sample,2),3);
            immagine(:,:,1)=Sample;
            immagine(:,:,2)=Sample;
            immagine(:,:,3)=Sample;
            black=0;
            saturation=1;
            gamma=1;
            max_image=max(max(max(immagine)));

            set(handles.SaturationValue,'String',num2str(saturation));
            set(handles.BlackValue,'String',num2str(black));
            set(handles.GammaValue,'String',num2str(gamma));

            immagine_plot=(abs(immagine./max_image-black)/saturation).^gamma;

            x_selected=round(size(immagine_plot,2)./2);
            y_selected=x_selected;

            immagine_plot(y_selected,1:end,1)=0;
            immagine_plot(y_selected,1:end,2)=0.7;
            immagine_plot(y_selected,1:end,3)=0;
            immagine_plot(:,x_selected,1)=0;
            immagine_plot(:,x_selected,2)=0.7;
            immagine_plot(:,x_selected,3)=0;

            image(immagine_plot,'Parent',handles.Immagine); axis equal; axis off;

            set(handles.x_selectedValue,'String',num2str(x_selected));
            set(handles.y_selectedValue,'String',num2str(y_selected));

            pixels_mean=1;
            set(handles.NpixelsMeanValue,'String',num2str(pixels_mean));

            %interferogram=squeeze(HyperMatrix_new(y_selected,x_selected,:));

            if mod(pixels_mean,2)==0 %pixels_mean even
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-(pixels_mean/2-1):y_selected+pixels_mean/2,x_selected-(pixels_mean/2-1):x_selected+pixels_mean/2,:),[1,2]))';
            else %pixels_mean odd
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-floor(pixels_mean/2):y_selected+floor(pixels_mean/2),x_selected-floor(pixels_mean/2):x_selected+floor(pixels_mean/2),:),[1,2]))';
            end

            interferogram=interferogram-mean(interferogram);

            t_min=min(t); %minimum of delay t axis considered for FT
            t_max=max(t); %maximum of delay t axis considered for FT

            set(handles.t_minValue,'String',t_min);
            set(handles.t_maxValue,'String',t_max);
            set(handles.min_t_value, 'String', num2str(round(t_min,1))); %min t value indicated in the GUI
            set(handles.max_t_value, 'String', num2str(round(t_max,1))); %max t value indicated in the GUI

            [~,pos_min]=min(abs(t-t_min));
            [~,pos_max]=min(abs(t-t_max));

            apod=Apodization(1,length(t(pos_min:pos_max)),length(t(pos_min:pos_max))./2,7); %interferogram apodization

            %spectrum=abs(FourierDir(t,interferogram'.*apod,f));
            spectrum=abs(FourierDir(t(pos_min:pos_max),interferogram(pos_min:pos_max).*apod,f));

            spectrum_y_fixed=0; %condition 'do not fix spectrum y-axis'
            set(handles.FixYSpectrum,'Value',spectrum_y_fixed);

            interf_y_fixed=0; %condition 'do not fix interferogram y-axis'
            set(handles.FixYInterferogram,'Value',interf_y_fixed);

            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);
            spectrum_y_lim=get(handles.Plot_spectr,'YLim');

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            % interf_y_lim=get(handles.Plot_Interf,'YLim');

            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            interf_y_lim=get(app.Plot_Interf,'YLim');


        end

        % Value changed function: BlackValue
        function BlackValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to BlackValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of BlackValue as text
            %        str2double(get(hObject,'String')) returns contents of BlackValue as a double

            global saturation black gamma immagine max_image x_selected y_selected ...
                spectrum f spectrum_y_fixed spectrum_y_lim t interferogram interf_y_fixed ...
                interf_y_lim t_min t_max

            black=str2num(get(handles.BlackValue,'String'));

            if black<0
                black=0;
            end

            if black>1
                black=1;
            end

            set(handles.BlackValue,'String',black);

            immagine_plot=(abs(immagine./max_image-black)/saturation).^gamma;

            immagine_plot(y_selected,1:end,1)=0;
            immagine_plot(y_selected,1:end,2)=0.7;
            immagine_plot(y_selected,1:end,3)=0;
            immagine_plot(:,x_selected,1)=0;
            immagine_plot(:,x_selected,2)=0.7;
            immagine_plot(:,x_selected,3)=0;

            image(immagine_plot,'Parent',handles.Immagine); axis equal; axis off;

            %plot again spectrum otherwise Matlab shrink it
            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end
            %
            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end

            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end

        end

        % Button pushed function: ExitButton
        function ExitButton_Callback(app, event)
            % --- Executes on button press in ExitButton.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ExitButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global dir U S V a b p_variables threshold n_SVs t spatial_signal_ratio
            %assignin('caller','threshold',threshold);
            %assignin('base','threshold',threshold); %assign to Matlab workspace

            %Compute denoised matrix considering n_SVs and threshold
            SVs=diag(S);

            SVs_swap=zeros(size(SVs));
            SVs_swap(1:end-(length(SVs)-n_SVs))=SVs(1:n_SVs); %keep only the first n_SVs SVs

            SVs=SVs_swap;
            clear SVs_swap

            SVs(spatial_signal_ratio<threshold)=0; %put to 0 all the SVs associated to low spatial_signal_ratio

            S_new=diag(SVs); %recalculate the S matrix

            X_new=(U*S_new*V')'; %recalculate the denoised dataset

            HyperMatrix=reshape(X_new,a,b,p_variables); %reshape properly the dataset

            %Save in the same directory as the chosen datacube
            save(strrep(strcat(dir, '_nSVs_',num2str(n_SVs),...
                '_th_', num2str(threshold)), '.','_'), 'HyperMatrix', 't');

            % Checks the size of the matrix, before saving
            HyProp = whos('HyperMatrix') ;
            Gigabytes = HyProp.bytes/2^30;

            if Gigabytes<1.8
                save(strrep(strcat(dir, '_nSVs_',num2str(n_SVs),...
                '_th_', num2str(threshold)), '.','_'), 'HyperMatrix', 't');
            else
                save(strrep(strcat(dir, '_nSVs_',num2str(n_SVs),...
                '_th_', num2str(threshold)), '.','_'), 'HyperMatrix', 't','-v7.3');
            end;


            close('SVD_Spatial_signal_ratio') %close the GUI
        end

        % Value changed function: FixYInterferogram
        function FixYInterferogram_Callback(app, event)
            % --- Executes on button press in FixYInterferogram.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to FixYInterferogram (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of FixYInterferogram
            global interf_y_fixed

            switch get(hObject,'Value')
                case 0 %button false (void)
                    interf_y_fixed=0;
                case 1 %button true (filled)
                    interf_y_fixed=1;
            end
        end

        % Value changed function: FixYSpectrum
        function FixYSpectrum_Callback(app, event)
            % --- Executes on button press in FixYSpectrum.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to FixYSpectrum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of FixYSpectrum

            global spectrum_y_fixed

            switch get(hObject,'Value')
                case 0 %button false (void)
                    spectrum_y_fixed=0;
                case 1 %button true (filled)
                    spectrum_y_fixed=1;
            end
        end

        % Value changed function: GammaValue
        function GammaValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to GammaValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of GammaValue as text
            %        str2double(get(hObject,'String')) returns contents of GammaValue as a double

            global saturation black gamma immagine max_image x_selected y_selected ...
                spectrum f spectrum_y_fixed spectrum_y_lim t interferogram interf_y_fixed ...
                interf_y_lim t_min t_max

            gamma=str2num(get(handles.GammaValue,'String'));

            if gamma<0
                gamma=0;
            end

            if gamma>1
                gamma=1;
            end

            set(handles.GammaValue,'String',gamma);

            immagine_plot=(abs(immagine./max_image-black)/saturation).^gamma;

            immagine_plot(y_selected,1:end,1)=0;
            immagine_plot(y_selected,1:end,2)=0.7;
            immagine_plot(y_selected,1:end,3)=0;
            immagine_plot(:,x_selected,1)=0;
            immagine_plot(:,x_selected,2)=0.7;
            immagine_plot(:,x_selected,3)=0;

            image(immagine_plot,'Parent',handles.Immagine); axis equal; axis off;

            %plot again spectrum otherwise Matlab shrink it
            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end
        end

        % Button pushed function: InterferogramInspection
        function InterferogramInspection_Callback(app, event)
            % --- Executes on button press in InterferogramInspection.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to InterferogramInspection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global interferogram t interf_y_lim threshold x_selected y_selected pixels_mean ...
                n_SVs t_min t_max

            figure;
            plot(t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            xlim([t_min t_max]);
            ylim(interf_y_lim);
            xlabel('Pseudodelay [\mum]');
            title(['Pixel (x=' num2str(x_selected-floor(pixels_mean/2)) ':' num2str(x_selected+floor(pixels_mean/2)) '; y=' num2str(y_selected-floor(pixels_mean/2)) ':' num2str(y_selected+floor(pixels_mean/2)) '); Threshold=' num2str(threshold) '; N SVs=' num2str(n_SVs)]);
        end

        % Value changed function: N_SVs_Edit
        function N_SVs_Edit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to N_SVs_Edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of N_SVs_Edit as text
            %        str2double(get(hObject,'String')) returns contents of N_SVs_Edit as a double
            global threshold yLimMin yLimMax xLimMin xLimMax ...
                spatial_signal_ratio U S V a b p_variables ...
                black saturation gamma max_image x_selected y_selected t f apod immagine ...
                spectrum HyperMatrix_new spectrum_y_lim spectrum_y_fixed interferogram ...
                interf_y_fixed interf_y_lim pixels_mean n_SVs t_min t_max

            n_SVs=str2num(get(handles.N_SVs_Edit,'String'));

            if n_SVs>length(diag(S)) %number of SVs cannot be larger than the size of S matrix
                n_SVs=length(diag(S));
            end

            if n_SVs<1
                n_SVs=1;
            end

            set(handles.N_SVs_Edit,'String',n_SVs);

            hhh=waitbar(1/2,'Please, wait...');

            plot(handles.Plot_Spatial_signal_ratio,spatial_signal_ratio(1:n_SVs),'b','linewidth',2); axis tight;
            set(handles.Plot_Spatial_signal_ratio,'YLim',[yLimMin yLimMax]);
            set(handles.Plot_Spatial_signal_ratio,'XLim',[xLimMin xLimMax]);

            hold on;
            yline(handles.Plot_Spatial_signal_ratio,threshold,'r','linewidth',1);
            hold off; %this is important to manage two different graphs in the GUI

            SVs=diag(S); %singular values (SVs)

            SVs_swap=zeros(size(SVs));
            SVs_swap(1:end-(length(SVs)-n_SVs))=SVs(1:n_SVs); %keep only the first n_SVs SVs

            SVs=SVs_swap;
            clear SVs_swap

            SVs(spatial_signal_ratio<threshold)=0; %put to 0 all the SVs associated to low spatial_signal_ratio

            S_new=diag(SVs); %recalculate the S matrix

            X_new=(U*S_new*V')'; %recalculate the denoised dataset

            HyperMatrix_new=reshape(X_new,a,b,p_variables); %reshape properly the dataset

            waitbar(1,hhh,'Please, wait...');
            close(hhh);

            Sample=sum(squeeze(abs(HyperMatrix_new)),3);
            immagine=zeros(size(Sample,1),size(Sample,2),3);
            immagine(:,:,1)=Sample;
            immagine(:,:,2)=Sample;
            immagine(:,:,3)=Sample;

            max_image=max(max(max(immagine)));

            immagine_plot=(abs(immagine./max_image-black)/saturation).^gamma;

            immagine_plot(y_selected,1:end,1)=0;
            immagine_plot(y_selected,1:end,2)=0.7;
            immagine_plot(y_selected,1:end,3)=0;
            immagine_plot(:,x_selected,1)=0;
            immagine_plot(:,x_selected,2)=0.7;
            immagine_plot(:,x_selected,3)=0;

            image(immagine_plot,'Parent',handles.Immagine); axis equal; axis off;

            %interferogram=squeeze(HyperMatrix_new(y_selected,x_selected,:));
            if mod(pixels_mean,2)==0 %pixels_mean even
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-(pixels_mean/2-1):y_selected+pixels_mean/2,x_selected-(pixels_mean/2-1):x_selected+pixels_mean/2,:),[1,2]))';
            else %pixels_mean odd
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-floor(pixels_mean/2):y_selected+floor(pixels_mean/2),x_selected-floor(pixels_mean/2):x_selected+floor(pixels_mean/2),:),[1,2]))';
            end
            interferogram=interferogram-mean(interferogram);

            [~,pos_min]=min(abs(t-t_min));
            [~,pos_max]=min(abs(t-t_max));

            apod=Apodization(1,length(t(pos_min:pos_max)),length(t(pos_min:pos_max))./2,7); %interferogram apodization

            %spectrum=abs(FourierDir(t,interferogram'.*apod,f));
            spectrum=abs(FourierDir(t(pos_min:pos_max),interferogram(pos_min:pos_max).*apod,f));


            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end
        end

        % Value changed function: NpixelsMeanValue
        function NpixelsMeanValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to NpixelsMeanValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of NpixelsMeanValue as text
            %        str2double(get(hObject,'String')) returns contents of NpixelsMeanValue as a double
            global immagine x_selected y_selected ...
                spectrum f t apod HyperMatrix_new spectrum_y_fixed spectrum_y_lim ...
                interferogram interf_y_fixed interf_y_lim pixels_mean t_min t_max

            pixels_mean=str2num(get(handles.NpixelsMeanValue,'String'));

            if pixels_mean<1
                pixels_mean=1;
            end

            if y_selected+floor(pixels_mean./2)>size(immagine,1)
                pixels_mean=2.*(size(immagine,1)-y_selected)+1; %the pixel mean square cannot overcome the lower image limit
            end

            if y_selected-floor(pixels_mean./2)<1
                pixels_mean=2.*y_selected-1; %the pixel mean square cannot overcome the upper image limit
            end

            if x_selected+floor(pixels_mean./2)>size(immagine,2)
                pixels_mean=2.*(size(immagine,2)-x_selected)+1; %the pixel mean square cannot overcome the right image limit
            end

            if x_selected-floor(pixels_mean./2)<1
                pixels_mean=2.*x_selected-1; %the pixel mean square cannot overcome the left image limit
            end

            set(handles.NpixelsMeanValue,'String',pixels_mean);

            %interferogram=squeeze(HyperMatrix_new(y_selected,x_selected,:));
            if mod(pixels_mean,2)==0 %pixels_mean even
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-(pixels_mean/2-1):y_selected+pixels_mean/2,x_selected-(pixels_mean/2-1):x_selected+pixels_mean/2,:),[1,2]))';
            else %pixels_mean odd
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-floor(pixels_mean/2):y_selected+floor(pixels_mean/2),x_selected-floor(pixels_mean/2):x_selected+floor(pixels_mean/2),:),[1,2]))';
            end

            interferogram=interferogram-mean(interferogram);

            [~,pos_min]=min(abs(t-t_min));
            [~,pos_max]=min(abs(t-t_max));

            apod=Apodization(1,length(t(pos_min:pos_max)),length(t(pos_min:pos_max))./2,7); %interferogram apodization

            %spectrum=abs(FourierDir(t,interferogram'.*apod,f));
            spectrum=abs(FourierDir(t(pos_min:pos_max),interferogram(pos_min:pos_max).*apod,f));

            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end

        end

        % Value changed function: SaturationValue
        function SaturationValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to SaturationValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of SaturationValue as text
            %        str2double(get(hObject,'String')) returns contents of SaturationValue as a double

            global saturation black gamma immagine max_image x_selected y_selected ...
                spectrum f spectrum_y_fixed spectrum_y_lim t interferogram interf_y_fixed ...
                interf_y_lim t_min t_max

            saturation=str2num(get(handles.SaturationValue,'String'));

            if saturation<=0
                saturation=0.1;
            end

            if saturation>1
                saturation=1;
            end

            set(handles.SaturationValue,'String',saturation);

            immagine_plot=(abs(immagine./max_image-black)/saturation).^gamma;

            immagine_plot(y_selected,1:end,1)=0;
            immagine_plot(y_selected,1:end,2)=0.7;
            immagine_plot(y_selected,1:end,3)=0;
            immagine_plot(:,x_selected,1)=0;
            immagine_plot(:,x_selected,2)=0.7;
            immagine_plot(:,x_selected,3)=0;

            image(immagine_plot,'Parent',handles.Immagine); axis equal; axis off;

            %plot again spectrum otherwise Matlab shrink it
            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end

        end

        % Button pushed function: SpectrumInspection
        function SpectrumInspection_Callback(app, event)
            % --- Executes on button press in SpectrumInspection.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to SpectrumInspection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global spectrum f spectrum_y_lim threshold x_selected y_selected pixels_mean ...
                n_SVs

            figure;
            plot(f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            xlim([min(f) max(f)]);
            ylim(spectrum_y_lim);
            xlabel('Pseudofrequency [\mum^{-1}]');
            title(['Pixel (x=' num2str(x_selected-floor(pixels_mean/2)) ':' num2str(x_selected+floor(pixels_mean/2)) '; y=' num2str(y_selected-floor(pixels_mean/2)) ':' num2str(y_selected+floor(pixels_mean/2)) '); Threshold=' num2str(threshold) '; N SVs=' num2str(n_SVs)]);

            WConversion_cryst;
        end

        % Value changed function: XLimMaxValue
        function XLimMaxValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to XLimMaxValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of XLimMaxValue as text
            %        str2double(get(hObject,'String')) returns contents of XLimMaxValue as a double
            global xLimMin xLimMax

            xLimMax=str2num(get(handles.XLimMaxValue,'String'));

            if xLimMax<=xLimMin
                xLimMax=xLimMin.*1.1;
            end

            set(handles.XLimMinValue,'String',xLimMin);

            set(handles.Plot_Spatial_signal_ratio,'XLim',[xLimMin xLimMax]);
        end

        % Value changed function: XLimMinValue
        function XLimMinValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to XLimMinValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of XLimMinValue as text
            %        str2double(get(hObject,'String')) returns contents of XLimMinValue as a double
            global xLimMin xLimMax

            xLimMin=str2num(get(handles.XLimMinValue,'String'));

            if xLimMin>=xLimMax
                xLimMin=xLimMax.*0.9;
            end

            set(handles.XLimMinValue,'String',xLimMin);

            set(handles.Plot_Spatial_signal_ratio,'XLim',[xLimMin xLimMax]);
        end

        % Value changed function: YLimMaxValue
        function YLimMaxValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to YLimMaxValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of YLimMaxValue as text
            %        str2double(get(hObject,'String')) returns contents of YLimMaxValue as a double

            global yLimMin yLimMax

            yLimMax=str2num(get(handles.YLimMaxValue,'String'));

            if yLimMax<=yLimMin
                yLimMax=yLimMin.*1.1;
            end

            set(handles.YLimMaxValue,'String',yLimMax);

            set(handles.Plot_Spatial_signal_ratio,'YLim',[yLimMin yLimMax]);
        end

        % Value changed function: YLimMinValue
        function YLimMinValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to YLimMinValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of YLimMinValue as text
            %        str2double(get(hObject,'String')) returns contents of YLimMinValue as a double

            global yLimMin yLimMax

            yLimMin=str2num(get(handles.YLimMinValue,'String'));

            if yLimMin>=yLimMax
                yLimMin=yLimMax.*0.9;
            end

            set(handles.YLimMinValue,'String',yLimMin);

            set(handles.Plot_Spatial_signal_ratio,'YLim',[yLimMin yLimMax]);
        end

        % Value changed function: t_maxValue
        function t_maxValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to t_maxValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of t_maxValue as text
            %        str2double(get(hObject,'String')) returns contents of t_maxValue as a double
            global t_min t_max interferogram t apod spectrum_y_lim spectrum_y_fixed ...
                f spectrum interf_y_fixed interf_y_lim

            t_max=str2num(get(handles.t_maxValue,'String'));

            [~,pos_min]=min(abs(t-t_min));
            [~,pos_max]=min(abs(t-t_max));

            t_max=t(pos_max);

            set(handles.t_maxValue,'String',t_max);

            apod=Apodization(1,length(t(pos_min:pos_max)),length(t(pos_min:pos_max))./2,7); %interferogram apodization

            %spectrum=abs(FourierDir(t,interferogram'.*apod,f));
            spectrum=abs(FourierDir(t(pos_min:pos_max),interferogram(pos_min:pos_max).*apod,f));

            spectrum_y_fixed=0; %condition 'do not fix spectrum y-axis'
            set(handles.FixYSpectrum,'Value',spectrum_y_fixed);

            interf_y_fixed=0; %condition 'do not fix interferogram y-axis'
            set(handles.FixYInterferogram,'Value',interf_y_fixed);

            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end
        end

        % Value changed function: t_minValue
        function t_minValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to t_minValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of t_minValue as text
            %        str2double(get(hObject,'String')) returns contents of t_minValue as a double
            global t_min t_max interferogram t apod spectrum_y_lim spectrum_y_fixed ...
                f spectrum interf_y_fixed interf_y_lim

            t_min=str2num(get(handles.t_minValue,'String'));

            [~,pos_min]=min(abs(t-t_min));
            [~,pos_max]=min(abs(t-t_max));

            t_min=t(pos_min);

            set(handles.t_minValue,'String',t_min);

            apod=Apodization(1,length(t(pos_min:pos_max)),length(t(pos_min:pos_max))./2,7); %interferogram apodization

            %spectrum=abs(FourierDir(t,interferogram'.*apod,f));
            spectrum=abs(FourierDir(t(pos_min:pos_max),interferogram(pos_min:pos_max).*apod,f));

            spectrum_y_fixed=0; %condition 'do not fix spectrum y-axis'
            set(handles.FixYSpectrum,'Value',spectrum_y_fixed);

            interf_y_fixed=0; %condition 'do not fix interferogram y-axis'
            set(handles.FixYInterferogram,'Value',interf_y_fixed);

            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end
        end

        % Value changed function: thresholdValue
        function thresholdValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to thresholdValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of thresholdValue as text
            %        str2double(get(hObject,'String')) returns contents of thresholdValue as a double

            global threshold yLimMin yLimMax xLimMin xLimMax ...
                spatial_signal_ratio U S V a b p_variables ...
                black saturation gamma max_image x_selected y_selected t f apod immagine ...
                spectrum HyperMatrix_new spectrum_y_lim spectrum_y_fixed interferogram ...
                interf_y_fixed interf_y_lim pixels_mean n_SVs t_min t_max

            threshold=str2num(get(handles.thresholdValue,'String'));

            if threshold>max(spatial_signal_ratio)
                threshold=max(spatial_signal_ratio);
            end

            if threshold<min(spatial_signal_ratio)
                threshold=min(spatial_signal_ratio);
            end

            set(handles.thresholdValue,'String',threshold);

            hhh=waitbar(1/2,'Please, wait...');

            plot(handles.Plot_Spatial_signal_ratio,spatial_signal_ratio(1:n_SVs),'b','linewidth',2); axis tight;
            set(handles.Plot_Spatial_signal_ratio,'YLim',[yLimMin yLimMax]);
            set(handles.Plot_Spatial_signal_ratio,'XLim',[xLimMin xLimMax]);

            hold on;
            yline(handles.Plot_Spatial_signal_ratio,threshold,'r','linewidth',1);
            hold off; %this is important to manage two different graphs in the GUI

            SVs=diag(S); %singular values (SVs)

            SVs_swap=zeros(size(SVs));
            SVs_swap(1:end-(length(SVs)-n_SVs))=SVs(1:n_SVs); %keep only the first n_SVs SVs

            SVs=SVs_swap;
            clear SVs_swap

            SVs(spatial_signal_ratio<threshold)=0; %put to 0 all the SVs associated to low spatial_signal_ratio

            S_new=diag(SVs); %recalculate the S matrix

            X_new=(U*S_new*V')'; %recalculate the denoised dataset

            HyperMatrix_new=reshape(X_new,a,b,p_variables); %reshape properly the dataset

            waitbar(1,hhh,'Please, wait...');
            close(hhh);

            Sample=sum(squeeze(abs(HyperMatrix_new)),3);
            immagine=zeros(size(Sample,1),size(Sample,2),3);
            immagine(:,:,1)=Sample;
            immagine(:,:,2)=Sample;
            immagine(:,:,3)=Sample;

            max_image=max(max(max(immagine)));

            immagine_plot=(abs(immagine./max_image-black)/saturation).^gamma;

            immagine_plot(y_selected,1:end,1)=0;
            immagine_plot(y_selected,1:end,2)=0.7;
            immagine_plot(y_selected,1:end,3)=0;
            immagine_plot(:,x_selected,1)=0;
            immagine_plot(:,x_selected,2)=0.7;
            immagine_plot(:,x_selected,3)=0;

            image(immagine_plot,'Parent',handles.Immagine); axis equal; axis off;

            %interferogram=squeeze(HyperMatrix_new(y_selected,x_selected,:));
            if mod(pixels_mean,2)==0 %pixels_mean even
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-(pixels_mean/2-1):y_selected+pixels_mean/2,x_selected-(pixels_mean/2-1):x_selected+pixels_mean/2,:),[1,2]))';
            else %pixels_mean odd
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-floor(pixels_mean/2):y_selected+floor(pixels_mean/2),x_selected-floor(pixels_mean/2):x_selected+floor(pixels_mean/2),:),[1,2]))';
            end
            interferogram=interferogram-mean(interferogram);

            [~,pos_min]=min(abs(t-t_min));
            [~,pos_max]=min(abs(t-t_max));

            apod=Apodization(1,length(t(pos_min:pos_max)),length(t(pos_min:pos_max))./2,7); %interferogram apodization
            %spectrum=abs(FourierDir(t,interferogram'.*apod,f));
            spectrum=abs(FourierDir(t(pos_min:pos_max),interferogram(pos_min:pos_max).*apod,f));


            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end
        end

        % Value changed function: x_selectedValue
        function x_selectedValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to x_selectedValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of x_selectedValue as text
            %        str2double(get(hObject,'String')) returns contents of x_selectedValue as a double

            global saturation black gamma immagine max_image x_selected y_selected ...
                spectrum f t apod HyperMatrix_new spectrum_y_fixed spectrum_y_lim ...
                interferogram interf_y_fixed interf_y_lim pixels_mean t_min t_max

            x_selected=str2num(get(handles.x_selectedValue,'String'));

            if x_selected<1
                x_selected=1;
            end

            if x_selected>size(immagine,2)
                x_selected=size(immagine,2);
            end

            set(handles.x_selectedValue,'String',x_selected);

            if y_selected+floor(pixels_mean./2)>size(immagine,1)
                pixels_mean=2.*(size(immagine,1)-y_selected)+1; %the pixel mean square cannot overcome the lower image limit
            end

            if y_selected-floor(pixels_mean./2)<1
                pixels_mean=2.*y_selected-1; %the pixel mean square cannot overcome the upper image limit
            end

            if x_selected+floor(pixels_mean./2)>size(immagine,2)
                pixels_mean=2.*(size(immagine,2)-x_selected)+1; %the pixel mean square cannot overcome the right image limit
            end

            if x_selected-floor(pixels_mean./2)<1
                pixels_mean=2.*x_selected-1; %the pixel mean square cannot overcome the left image limit
            end

            set(handles.NpixelsMeanValue,'String',pixels_mean);

            immagine_plot=(abs(immagine./max_image-black)/saturation).^gamma;

            immagine_plot(y_selected,1:end,1)=0;
            immagine_plot(y_selected,1:end,2)=0.7;
            immagine_plot(y_selected,1:end,3)=0;
            immagine_plot(:,x_selected,1)=0;
            immagine_plot(:,x_selected,2)=0.7;
            immagine_plot(:,x_selected,3)=0;

            image(immagine_plot,'Parent',handles.Immagine); axis equal; axis off;

            %interferogram=squeeze(HyperMatrix_new(y_selected,x_selected,:));
            if mod(pixels_mean,2)==0 %pixels_mean even
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-(pixels_mean/2-1):y_selected+pixels_mean/2,x_selected-(pixels_mean/2-1):x_selected+pixels_mean/2,:),[1,2]))';
            else %pixels_mean odd
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-floor(pixels_mean/2):y_selected+floor(pixels_mean/2),x_selected-floor(pixels_mean/2):x_selected+floor(pixels_mean/2),:),[1,2]))';
            end
            interferogram=interferogram-mean(interferogram);

            [~,pos_min]=min(abs(t-t_min));
            [~,pos_max]=min(abs(t-t_max));

            apod=Apodization(1,length(t(pos_min:pos_max)),length(t(pos_min:pos_max))./2,7); %interferogram apodization

            %spectrum=abs(FourierDir(t,interferogram'.*apod,f));
            spectrum=abs(FourierDir(t(pos_min:pos_max),interferogram(pos_min:pos_max).*apod,f));

            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end
        end

        % Value changed function: y_selectedValue
        function y_selectedValue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to y_selectedValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of y_selectedValue as text
            %        str2double(get(hObject,'String')) returns contents of y_selectedValue as a double

            global saturation black gamma immagine max_image x_selected y_selected ...
                spectrum f t apod HyperMatrix_new spectrum_y_fixed spectrum_y_lim ...
                interferogram interf_y_fixed interf_y_lim pixels_mean t_min t_max

            y_selected=str2num(get(handles.y_selectedValue,'String'));

            if y_selected<1
                y_selected=1;
            end

            if y_selected>size(immagine,2)
                y_selected=size(immagine,2);
            end

            set(handles.y_selectedValue,'String',y_selected);

            if y_selected+floor(pixels_mean./2)>size(immagine,1)
                pixels_mean=2.*(size(immagine,1)-y_selected)+1; %the pixel mean square cannot overcome the lower image limit
            end

            if y_selected-floor(pixels_mean./2)<1
                pixels_mean=2.*y_selected-1; %the pixel mean square cannot overcome the upper image limit
            end

            if x_selected+floor(pixels_mean./2)>size(immagine,2)
                pixels_mean=2.*(size(immagine,2)-x_selected)+1; %the pixel mean square cannot overcome the right image limit
            end

            if x_selected-floor(pixels_mean./2)<1
                pixels_mean=2.*x_selected-1; %the pixel mean square cannot overcome the left image limit
            end

            set(handles.NpixelsMeanValue,'String',pixels_mean);

            immagine_plot=(abs(immagine./max_image-black)/saturation).^gamma;

            immagine_plot(y_selected,1:end,1)=0;
            immagine_plot(y_selected,1:end,2)=0.7;
            immagine_plot(y_selected,1:end,3)=0;
            immagine_plot(:,x_selected,1)=0;
            immagine_plot(:,x_selected,2)=0.7;
            immagine_plot(:,x_selected,3)=0;

            image(immagine_plot,'Parent',handles.Immagine); axis equal; axis off;

            %interferogram=squeeze(HyperMatrix_new(y_selected,x_selected,:));
            if mod(pixels_mean,2)==0 %pixels_mean even
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-(pixels_mean/2-1):y_selected+pixels_mean/2,x_selected-(pixels_mean/2-1):x_selected+pixels_mean/2,:),[1,2]))';
            else %pixels_mean odd
                interferogram=squeeze(mean(HyperMatrix_new(y_selected-floor(pixels_mean/2):y_selected+floor(pixels_mean/2),x_selected-floor(pixels_mean/2):x_selected+floor(pixels_mean/2),:),[1,2]))';
            end
            interferogram=interferogram-mean(interferogram);

            [~,pos_min]=min(abs(t-t_min));
            [~,pos_max]=min(abs(t-t_max));

            apod=Apodization(1,length(t(pos_min:pos_max)),length(t(pos_min:pos_max))./2,7); %interferogram apodization

            %spectrum=abs(FourierDir(t,interferogram'.*apod,f));
            spectrum=abs(FourierDir(t(pos_min:pos_max),interferogram(pos_min:pos_max).*apod,f));

            plot(handles.Plot_spectr,f,spectrum,'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(handles.Plot_spectr,'XLim',[min(f) max(f)]);

            if spectrum_y_fixed==1 %if the y axis is fixed
                set(handles.Plot_spectr,'YLim',spectrum_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                spectrum_y_lim=get(handles.Plot_spectr,'YLim'); %take the new limits
            end

            % plot(handles.Plot_Interf,t,interferogram,'Color',[0 0.7 0],'linewidth',0.5);
            % hold off;
            % set(handles.Plot_Interf,'XLim',[t_min t_max]);
            %
            % if interf_y_fixed==1 %if the y axis is fixed
            %     set(handles.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            % else %if the y axis is not fixed
            %     interf_y_lim=get(handles.Plot_Interf,'YLim'); %take the new limits
            % end
            app.Plot_Interf.Visible='on';

            plot(app.Plot_Interf, t, interferogram, 'Color',[0 0.7 0],'linewidth',0.5);
            hold off;
            set(app.Plot_Interf, 'XLim',[t_min t_max]);
            if interf_y_fixed==1 %if the y axis is fixed
                set(app.Plot_Interf,'YLim',interf_y_lim); %keep the y axis limits
            else %if the y axis is not fixed
                interf_y_lim=app.Plot_Interf.YLim; %take the new limits
            end
        end

        % Value changing function: N_SVs_Edit
        function N_SVs_EditValueChanging(app, event)
            changingValue = event.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SVD_Spatial_signal_ratio';
            app.figure1.Tag = 'figure1';
            app.Plot_Spatial_signal_ratio = uiaxes(app.figure1);
            app.Plot_Spatial_signal_ratio.Tag = 'Plot_Spatial_signal_ratio';
            app.Immagine = uiaxes(app.figure1);
            app.Immagine.Tag = 'Immagine';
            app.Plot_spectr = uiaxes(app.figure1);
            app.Plot_spectr.Tag = 'Plot_spectr';
            app.Plot_Interf = uiaxes(app.figure1);
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.uibuttongroup3.Title = 'X Limits (zoom x axis)';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Spatial Signal Ratio';
            app.text22 = uilabel(app.figure1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'N Singular Values';
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.Title = 'Image parameters';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.SaturationValue = uieditfield(app.uibuttongroup2, 'text');
            app.SaturationValue.ValueChangedFcn = createCallbackFcn(app, @SaturationValue_Callback, true);
            app.SaturationValue.Tag = 'SaturationValue';
            app.SaturationValue.Value = '1';
            app.text15 = uilabel(app.uibuttongroup2);
            app.text15.Tag = 'text15';
            app.text15.Text = 'saturation';
            app.BlackValue = uieditfield(app.uibuttongroup2, 'text');
            app.BlackValue.ValueChangedFcn = createCallbackFcn(app, @BlackValue_Callback, true);
            app.BlackValue.Tag = 'BlackValue';
            app.BlackValue.Value = '0';
            app.text16 = uilabel(app.uibuttongroup2);
            app.text16.Tag = 'text16';
            app.text16.Text = 'black';
            app.GammaValue = uieditfield(app.uibuttongroup2, 'text');
            app.GammaValue.ValueChangedFcn = createCallbackFcn(app, @GammaValue_Callback, true);
            app.GammaValue.Tag = 'GammaValue';
            app.GammaValue.Value = '1';
            app.text17 = uilabel(app.uibuttongroup2);
            app.text17.Tag = 'text17';
            app.text17.Text = 'gamma';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Y Limits (zoom y axis)';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.YLimMinValue = uieditfield(app.uibuttongroup1, 'text');
            app.YLimMinValue.ValueChangedFcn = createCallbackFcn(app, @YLimMinValue_Callback, true);
            app.YLimMinValue.Tag = 'YLimMinValue';
            app.YLimMinValue.Value = '0';
            app.YLimMaxValue = uieditfield(app.uibuttongroup1, 'text');
            app.YLimMaxValue.ValueChangedFcn = createCallbackFcn(app, @YLimMaxValue_Callback, true);
            app.YLimMaxValue.Tag = 'YLimMaxValue';
            app.YLimMaxValue.Value = '10';
            app.text13 = uilabel(app.uibuttongroup1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'min';
            app.text14 = uilabel(app.uibuttongroup1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'max';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Threshold';
            app.thresholdValue = uieditfield(app.figure1, 'text');
            app.thresholdValue.ValueChangedFcn = createCallbackFcn(app, @thresholdValue_Callback, true);
            app.thresholdValue.Tag = 'thresholdValue';
            app.thresholdValue.Value = '0.5';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Intensity';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Spectrum';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Selected pixel';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'x';
            app.x_selectedValue = uieditfield(app.figure1, 'text');
            app.x_selectedValue.ValueChangedFcn = createCallbackFcn(app, @x_selectedValue_Callback, true);
            app.x_selectedValue.Tag = 'x_selectedValue';
            app.x_selectedValue.Value = '50';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'y';
            app.y_selectedValue = uieditfield(app.figure1, 'text');
            app.y_selectedValue.ValueChangedFcn = createCallbackFcn(app, @y_selectedValue_Callback, true);
            app.y_selectedValue.Tag = 'y_selectedValue';
            app.y_selectedValue.Value = '100';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'Pseudofrequency';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'n° singular value';
            app.SpectrumInspection = uibutton(app.figure1, 'push');
            app.SpectrumInspection.ButtonPushedFcn = createCallbackFcn(app, @SpectrumInspection_Callback, true);
            app.SpectrumInspection.Tag = 'SpectrumInspection';
            app.SpectrumInspection.Text = 'Spectrum Inspection';
            app.FixYSpectrum = uicheckbox(app.figure1);
            app.FixYSpectrum.ValueChangedFcn = createCallbackFcn(app, @FixYSpectrum_Callback, true);
            app.FixYSpectrum.Tag = 'FixYSpectrum';
            app.FixYSpectrum.Text = 'Fix spectrum y axis limits';
            app.ExitButton = uibutton(app.figure1, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_Callback, true);
            app.ExitButton.Tag = 'ExitButton';
            app.ExitButton.Text = 'Select current threshold and EXIT';
            app.text18 = uilabel(app.figure1);
            app.text18.Text = 'Interferogram';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Pseudodelay';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'N Pixels mean';
            app.NpixelsMeanValue = uieditfield(app.figure1, 'text');
            app.NpixelsMeanValue.ValueChangedFcn = createCallbackFcn(app, @NpixelsMeanValue_Callback, true);
            app.NpixelsMeanValue.Tag = 'NpixelsMeanValue';
            app.NpixelsMeanValue.Value = '1';
            app.InterferogramInspection = uibutton(app.figure1, 'push');
            app.InterferogramInspection.ButtonPushedFcn = createCallbackFcn(app, @InterferogramInspection_Callback, true);
            app.InterferogramInspection.Tag = 'InterferogramInspection';
            app.InterferogramInspection.Text = 'Interferogram Inspection';
            app.FixYInterferogram = uicheckbox(app.figure1);
            app.FixYInterferogram.ValueChangedFcn = createCallbackFcn(app, @FixYInterferogram_Callback, true);
            app.FixYInterferogram.Tag = 'FixYInterferogram';
            app.FixYInterferogram.Text = 'Fix interferogram y axis limits';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = '(NxN nearest neighbours in a square around selected point)';
            app.N_SVs_Edit = uieditfield(app.figure1, 'text');
            app.N_SVs_Edit.ValueChangedFcn = createCallbackFcn(app, @N_SVs_Edit_Callback, true);
            app.N_SVs_Edit.ValueChangingFcn = createCallbackFcn(app, @N_SVs_EditValueChanging, true);
            app.N_SVs_Edit.Tag = 'N_SVs_Edit';
            app.N_SVs_Edit.Value = '500';
            app.XLimMinValue = uieditfield(app.figure1, 'text');
            app.XLimMinValue.ValueChangedFcn = createCallbackFcn(app, @XLimMinValue_Callback, true);
            app.XLimMinValue.Tag = 'XLimMinValue';
            app.XLimMinValue.Value = '0';
            app.XLimMaxValue = uieditfield(app.figure1, 'text');
            app.XLimMaxValue.ValueChangedFcn = createCallbackFcn(app, @XLimMaxValue_Callback, true);
            app.XLimMaxValue.Tag = 'XLimMaxValue';
            app.XLimMaxValue.Value = '500';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'min';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'max';
            app.Interferogram_crop = uibuttongroup(app.figure1);
            app.Interferogram_crop.Title = 'Interferogram crop';
            app.Interferogram_crop.Tag = 'Interferogram_crop';
            app.max_t_value = uilabel(app.Interferogram_crop);
            app.max_t_value.Tag = 'max_t_value';
            app.max_t_value.Text = '11000';
            app.min_t_value = uilabel(app.Interferogram_crop);
            app.min_t_value.Tag = 'min_t_value';
            app.min_t_value.Text = '9000';
            app.t_minValue = uieditfield(app.Interferogram_crop, 'text');
            app.t_minValue.ValueChangedFcn = createCallbackFcn(app, @t_minValue_Callback, true);
            app.t_minValue.Tag = 't_minValue';
            app.t_minValue.Value = '9000';
            app.t_maxValue = uieditfield(app.Interferogram_crop, 'text');
            app.t_maxValue.ValueChangedFcn = createCallbackFcn(app, @t_maxValue_Callback, true);
            app.t_maxValue.Tag = 't_maxValue';
            app.t_maxValue.Value = '11000';
            app.text25 = uilabel(app.Interferogram_crop);
            app.text25.Tag = 'text25';
            app.text25.Text = 'min';
            app.text26 = uilabel(app.Interferogram_crop);
            app.text26.Tag = 'text26';
            app.text26.Text = 'max';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SVD_Spatial_signal_ratio_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SVD_Spatial_signal_ratio_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end