classdef SVD_denoising < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SVDdenoisingPanel             matlab.ui.container.Panel
        SuggestedvalueLabel_2         matlab.ui.control.Label
        Singularvalues                matlab.ui.control.NumericEditField
        SingularvaluesEditFieldLabel  matlab.ui.control.Label
        SuggestedvalueLabel           matlab.ui.control.Label
        t_axisandt_DelayCorrectionLabel  matlab.ui.control.Label
        TemporalhypercubeLabel        matlab.ui.control.Label
        DenoiseButton                 matlab.ui.control.Button
        ConversiontoolButton          matlab.ui.control.Button
        uibuttongroup1                matlab.ui.container.ButtonGroup
        pseudo_num                    matlab.ui.control.NumericEditField
        Label_pseudo_num              matlab.ui.control.Label
        Label_pseudo_max              matlab.ui.control.Label
        Label_pseudo_min              matlab.ui.control.Label
        pseudo_max                    matlab.ui.control.NumericEditField
        pseudo_min                    matlab.ui.control.NumericEditField
        ImageRatio                    matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        LoadDelaycorrectionButton     matlab.ui.control.Button
        LoadtemporalhypercubeButton   matlab.ui.control.Button
        IntensityMap                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Filename;
        Filepath;
        FilenameCorr;
        FilepathCorr;
        Datacube;
        Rows;
        Columns;
        Depth;
        Averages;
        t;
        tCorrected;
        n_SVs;
        U;
        S;
        V;
        f;
        SSR;
        SSR_threshold;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadtemporalhypercubeButton
        function LoadtemporalhypercubeButtonPushed(app, event)
            %Load temporal hypercube
            %I expect the datacube to be a variable called "HyperMatrix"
            %in the .mat file
            app.LoadtemporalhypercubeButton.Enable = "off";

            [app.Filename, app.Filepath] = uigetfile('*.mat', 'Select a file', "C:\");
            % we re-focus on the main page
            figure(app.UIFigure)

            % we load the chosen .mat file in the workspace
            load(strcat(app.Filepath,app.Filename));

            %Update the name on the window
            app.TemporalhypercubeLabel.Text=app.Filename; 

            app.Datacube = HyperMatrix;
            app.t=t;

            % Dimensions of the datacube
            rows = size(app.Datacube,1);
            columns = size(app.Datacube,2);
            depth = size(app.Datacube,3);

            app.Rows = rows;
            app.Columns = columns;
            app.Depth = depth;

            %Mean intensity
            Averages=mean(app.Datacube,3);

            app.Averages=Averages;

            imagesc(app.IntensityMap, Averages);
            colormap(app.IntensityMap, "parula")
            pbaspect(app.IntensityMap, [app.Columns, app.Rows, 1]);
            app.IntensityMap.XLim = [0 app.Columns];
            app.IntensityMap.YLim = [0 app.Rows];
        end

        % Button down function: IntensityMap
        function IntensityMapButtonDown(app, event)

        end

        % Button pushed function: LoadDelaycorrectionButton
        function LoadDelaycorrectionButtonPushed(app, event)
            %Load delay correction
            %I expect two vectors called "t_axis" and "t_delayCorrection"
            %in the .mat file
            app.LoadDelaycorrectionButton.Enable = "off";

            [app.FilenameCorr, app.FilepathCorr] = uigetfile('*.mat', 'Select a file', "C:\");
            % we re-focus on the main page
            figure(app.UIFigure)

            % we load the chosen .mat file in the workspace
            load(strcat(app.FilepathCorr,app.FilenameCorr));

            % Update the name on window
            app.t_axisandt_DelayCorrectionLabel.Text=app.FilenameCorr; 

            % we apply the delay correction to temporal axis
            app.tCorrected=app.t+interp1(t_axis, t_delayCorrection, app.t);
        end

        % Value changed function: ImageRatio
        function ImageRatioValueChanged(app, event)
            value = app.ImageRatio.Value;

        end

        % Button pushed function: ConversiontoolButton
        function ConversiontoolButtonPushed(app, event)
            WConversion_cryst_App(app)

        end

        % Button pushed function: DenoiseButton
        function DenoiseButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Compute svd

            n_observations=app.Rows.*app.Columns;
            X=reshape(app.Datacube,[n_observations,app.Depth])'; %X: rows spectral axis, column spatial content
            app.n_SVs=app.Singularvalues.Value; 
            
            [U,S,V]=svds(X,app.n_SVs); %SVD (only first n_SVs Singular Values)
            app.U=U;
            app.V=V;
            app.S=S;
            %Compute the pseudofrequency vector
            app.f= linspace(app.pseudo_max.Value, app.pseudo_min.Value,app.pseudo_num.Value);

            %Compute SSR
            spatial_signal_ratio=zeros(1,size(V,2));
            a=app.Rows;
            b=app.Columns; 
            edge_ratio=sqrt(app.ImageRatio.Value); %ratio between the edge of the central part

            a_start_mappa=round((a-a.*edge_ratio)./2);
            b_start_mappa=round((b-b.*edge_ratio)./2);
            a_end_mappa=a_start_mappa+round(a.*edge_ratio);
            b_end_mappa=b_start_mappa+round(b.*edge_ratio);

            %V columns represent the spatial weights for each spectral eigenfunction (PC)
            %contained in the rows of U
            for index=1:size(V,2) %index slides through the SVs (each associated to a column of V)

                SV_V_comp=reshape(V(:,index),[a,b]); %consider the index-th column of V as spatial image
                S_V_comp_FT=fft2(SV_V_comp); %take the spatial FT
                mappa=fftshift(abs(S_V_comp_FT));
                %in the FT map (mappa) the signal is located only in the center (low
                %spatial frequencies), the noise could be located everywhere
                Signal=mappa(a_start_mappa:a_end_mappa,b_start_mappa:b_end_mappa); %the central part is consider as Signal
                Noise=mappa;
                Noise(a_start_mappa:a_end_mappa,b_start_mappa:b_end_mappa)=0; %the outside part is considered as only noise

                %define the "figure of merit" Spatial-Signal Ratio as the ratio between
                %the integral of the intensity in the Signal and in the Noise part of
                %the FT image
                spatial_signal_ratio(index)=sum(Signal(:))./sum(Noise(:)); %spatial-signal ratio associated to the index-th SV
            end
            app.SSR=spatial_signal_ratio; 

            %Compute threshold
            app.SSR_threshold=app.ImageRatio.Value*2; %starting threshold for Spatial Signal Ration to be considered

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Open SVD_SpatialSignalRatio app
            %SVD_SpatialSignalRatio(app);

            SVD_Spatial_signal_ratio_App(app, app.U, app.S, app.V, app.Rows,...
                app.Columns,app.Depth,app.SSR,app.SSR_threshold,app.n_SVs, app.tCorrected, app.f,...
                strcat(app.Filepath, app.Filename))

            % U=varargin{1}; %U matrix of SVD of HyperMatrix (temporal hypercube)
            % S=varargin{2}; %S matrix of SVD of HyperMatrix (temporal hypercube)
            % V=varargin{3}; %V matrix of SVD of HyperMatrix (temporal hypercube)
            % a=varargin{4}; %this is size(HyperMatrix,1); y dimension
            % b=varargin{5}; %this is size(HyperMatrix,2); x dimension
            % p_variables=varargin{6}; %this is size(HyperMatrix,3); delay dimension
            % spatial_signal_ratio=varargin{7}; %spatial signal ratio vector
            % threshold=varargin{8}; %first threshold for Spatial Signal Ratio to be considered
            % n_SVs=varargin{9}; %number of Singular Values (SVs) considered
            % t=varargin{10}; %delay (or motor position axis)
            % f=varargin{11}; %frequency (or pseudofrequency axis)
        end

        % Value changed function: Singularvalues
        function SingularvaluesValueChanged(app, event)
            value = app.Singularvalues.Value;

        end

        % Value changed function: pseudo_num
        function pseudo_numValueChanged(app, event)
            value = app.pseudo_num.Value;
            
        end

        % Value changed function: pseudo_min
        function pseudo_minValueChanged2(app, event)
            value = app.pseudo_min.Value;
            
        end

        % Value changed function: pseudo_max
        function pseudo_maxValueChanged2(app, event)
            value = app.pseudo_max.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SVDdenoisingPanel = uipanel(app.UIFigure);
            app.SVDdenoisingPanel.Title = 'SVD denoising';
            app.IntensityMap = uiaxes(app.SVDdenoisingPanel);
            title(app.IntensityMap, 'Intensity')
            xlabel(app.IntensityMap, 'x')
            ylabel(app.IntensityMap, 'y')
            zlabel(app.IntensityMap, 'Z')
            app.IntensityMap.ButtonDownFcn = createCallbackFcn(app, @IntensityMapButtonDown, true);
            app.LoadtemporalhypercubeButton = uibutton(app.SVDdenoisingPanel, 'push');
            app.LoadtemporalhypercubeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtemporalhypercubeButtonPushed, true);
            app.LoadtemporalhypercubeButton.Text = 'Load temporal hypercube';
            app.LoadDelaycorrectionButton = uibutton(app.SVDdenoisingPanel, 'push');
            app.LoadDelaycorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDelaycorrectionButtonPushed, true);
            app.LoadDelaycorrectionButton.Text = 'Load Delay correction';
            app.Label = uilabel(app.SVDdenoisingPanel);
            app.Label.Text = 'Image ratio';
            app.ImageRatio = uieditfield(app.SVDdenoisingPanel, 'numeric');
            app.ImageRatio.ValueChangedFcn = createCallbackFcn(app, @ImageRatioValueChanged, true);
            app.ImageRatio.Value = 0.25;
            app.uibuttongroup1 = uibuttongroup(app.SVDdenoisingPanel);
            app.uibuttongroup1.Title = 'Pseudofrequency axis';
            app.pseudo_min = uieditfield(app.uibuttongroup1, 'numeric');
            app.pseudo_min.ValueChangedFcn = createCallbackFcn(app, @pseudo_minValueChanged2, true);
            app.pseudo_max = uieditfield(app.uibuttongroup1, 'numeric');
            app.pseudo_max.ValueChangedFcn = createCallbackFcn(app, @pseudo_maxValueChanged2, true);
            app.pseudo_max.Value = 10;
            app.Label_pseudo_min = uilabel(app.uibuttongroup1);
            app.Label_pseudo_min.Text = 'min';
            app.Label_pseudo_max = uilabel(app.uibuttongroup1);
            app.Label_pseudo_max.Text = 'max';
            app.Label_pseudo_num = uilabel(app.uibuttongroup1);
            app.Label_pseudo_num.Text = 'number';
            app.pseudo_num = uieditfield(app.uibuttongroup1, 'numeric');
            app.pseudo_num.ValueChangedFcn = createCallbackFcn(app, @pseudo_numValueChanged, true);
            app.pseudo_num.Value = 300;
            app.ConversiontoolButton = uibutton(app.SVDdenoisingPanel, 'push');
            app.ConversiontoolButton.ButtonPushedFcn = createCallbackFcn(app, @ConversiontoolButtonPushed, true);
            app.ConversiontoolButton.Text = 'Conversion tool';
            app.DenoiseButton = uibutton(app.SVDdenoisingPanel, 'push');
            app.DenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @DenoiseButtonPushed, true);
            app.DenoiseButton.Text = 'Denoise!';
            app.TemporalhypercubeLabel = uilabel(app.SVDdenoisingPanel);
            app.TemporalhypercubeLabel.Text = '*Temporal hypercube and motor positions axis ';
            app.t_axisandt_DelayCorrectionLabel = uilabel(app.SVDdenoisingPanel);
            app.t_axisandt_DelayCorrectionLabel.Text = '*t_axis and t_DelayCorrection';
            app.SuggestedvalueLabel = uilabel(app.SVDdenoisingPanel);
            app.SuggestedvalueLabel.Text = '*Suggested value ';
            app.SingularvaluesEditFieldLabel = uilabel(app.SVDdenoisingPanel);
            app.SingularvaluesEditFieldLabel.Text = '# Singular values';
            app.Singularvalues = uieditfield(app.SVDdenoisingPanel, 'numeric');
            app.Singularvalues.ValueChangedFcn = createCallbackFcn(app, @SingularvaluesValueChanged, true);
            app.Singularvalues.Value = 50;
            app.SuggestedvalueLabel_2 = uilabel(app.SVDdenoisingPanel);
            app.SuggestedvalueLabel_2.Text = '*Suggested value ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SVD_denoising

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end