classdef Undersampling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ComputeButton                  matlab.ui.control.Button
        SelectedfileLabel              matlab.ui.control.Label
        UndersamplingstepcalculationLabel  matlab.ui.control.Label
        LoadcalibrationButton          matlab.ui.control.Button
        MinWavenm                      matlab.ui.control.NumericEditField
        MinwavelengthnmEditFieldLabel  matlab.ui.control.Label
        MaxWavenm                      matlab.ui.control.NumericEditField
        MaxwavelengthnmEditFieldLabel  matlab.ui.control.Label
        F                              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Filename;
        Filepath;
        f_0;
        fr_real0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadcalibrationButton
        function LoadcalibrationButtonPushed(app, event)
             %Load frequency calibration file
            %I expect the file to contain 
            % - f_0: pseudofrequency
            % - fr_real0: real optical frequency in THz
 
            app.LoadcalibrationButton.Enable = "off";

            [app.Filename, app.Filepath] = uigetfile('*.mat', 'Select a file', "C:\");
            % we re-focus on the main page
            figure(app.UIFigure)

            % we load the chosen .mat file in the workspace
            load(strcat(app.Filepath,app.Filename));
            app.f_0=f_0; 
            app.fr_real0=fr_real0; 

            %Update the name on the window
            app.SelectedfileLabel.Text=app.Filename; 
            

        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            c=299792458; %speed of light

            %Translate wavelengths in pseudofrequencies [um-1]
             fr_real_min= c/app.MinWavenm.Value*10^(-3); %THz
             fr_real_max= c/app.MaxWavenm.Value*10^(-3); %THz             
            
             f_min=interp1(app.fr_real0, app.f_0, fr_real_min);
             f_max=interp1(app.fr_real0, app.f_0, fr_real_max);

            %Compute function F(delta_x)
            delta_x=linspace(2.5,50,500); 
            F=1./delta_x-2*f_min+floor(2*f_min*delta_x)./delta_x+...
                2*(f_max-f_min); 

            %Plot function F(delta_x)
            plot(app.F,delta_x, F)
            yline(app.F, 0, '--r')

        end

        % Button down function: F
        function FButtonDown(app, event)
            % Create new figure with a copy of the axes
            FigureF = figure;
            ExpandedAxesF = copyobj(app.F, FigureF);
            set(ExpandedAxesF, 'Units', 'normalized',...
                'Position', get(FigureF, 'DefaultAxesPosition'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.F = uiaxes(app.UIFigure);
            xlabel(app.F, 'Step \Deltax [\mum]')
            ylabel(app.F, 'F(\Deltax)')
            zlabel(app.F, 'Z')
            app.F.ButtonDownFcn = createCallbackFcn(app, @FButtonDown, true);
            app.MaxwavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxwavelengthnmEditFieldLabel.Text = 'Max wavelength [nm]';
            app.MaxWavenm = uieditfield(app.UIFigure, 'numeric');
            app.MinwavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.MinwavelengthnmEditFieldLabel.Text = 'Min wavelength [nm]';
            app.MinWavenm = uieditfield(app.UIFigure, 'numeric');
            app.LoadcalibrationButton = uibutton(app.UIFigure, 'push');
            app.LoadcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcalibrationButtonPushed, true);
            app.LoadcalibrationButton.Text = 'Load calibration';
            app.UndersamplingstepcalculationLabel = uilabel(app.UIFigure);
            app.UndersamplingstepcalculationLabel.Text = 'Undersampling: step calculation';
            app.SelectedfileLabel = uilabel(app.UIFigure);
            app.SelectedfileLabel.Text = 'Selected file';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Undersampling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end